classdef Enthalpy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnthalpyUIFigure     matlab.ui.Figure
        PHTDiagramgivenIsobarPressuresButton  matlab.ui.control.Button
        TwoPhaseEnvelopeonaTHDiagramButton  matlab.ui.control.Button
        IsobargivenPressureonaTHDiagramButton  matlab.ui.control.Button
        BackButton           matlab.ui.control.Button
        ChooseanoptionLabel  matlab.ui.control.Label
        PHTDiagramgivenIsothermTemperaturesButton  matlab.ui.control.Button
        TwoPhaseEnvelopeonaPHDiagramButton  matlab.ui.control.Button
        IsothermgivenTemperatureonaPHDiagramButton  matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.EnthalpyUIFigure,'center');
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            EoS_Simulator;
            delete(app);
        end

        % Button pushed function: 
        % IsothermgivenTemperatureonaPHDiagramButton
        function IsothermgivenTemperatureonaPHDiagramButtonPushed(app, event)
            Enthalpy1;
            delete(app);
        end

        % Button pushed function: TwoPhaseEnvelopeonaPHDiagramButton
        function TwoPhaseEnvelopeonaPHDiagramButtonPushed(app, event)
            Enthalpy2;
            delete(app);
        end

        % Button pushed function: PHTDiagramgivenIsothermTemperaturesButton
        function PHTDiagramgivenIsothermTemperaturesButtonPushed(app, event)
            Enthalpy3;
            delete(app);
        end

        % Button pushed function: IsobargivenPressureonaTHDiagramButton
        function IsobargivenPressureonaTHDiagramButtonPushed(app, event)
            Enthalpy4;
            delete(app);
        end

        % Button pushed function: TwoPhaseEnvelopeonaTHDiagramButton
        function TwoPhaseEnvelopeonaTHDiagramButtonPushed(app, event)
            Enthalpy5;
            delete(app);
        end

        % Button pushed function: PHTDiagramgivenIsobarPressuresButton
        function PHTDiagramgivenIsobarPressuresButtonPushed(app, event)
            Enthalpy6;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EnthalpyUIFigure = uifigure('Visible', 'off');
            app.EnthalpyUIFigure.Name = 'Enthalpy';
            app.EnthalpyUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Image = uiimage(app.EnthalpyUIFigure);
            app.Image.ImageSource = 'Logo.PNG';
            app.IsothermgivenTemperatureonaPHDiagramButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.IsothermgivenTemperatureonaPHDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @IsothermgivenTemperatureonaPHDiagramButtonPushed, true);
            app.IsothermgivenTemperatureonaPHDiagramButton.Text = 'Isotherm given Temperature on a PH Diagram';
            app.TwoPhaseEnvelopeonaPHDiagramButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.TwoPhaseEnvelopeonaPHDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @TwoPhaseEnvelopeonaPHDiagramButtonPushed, true);
            app.TwoPhaseEnvelopeonaPHDiagramButton.Text = 'Two-Phase Envelope on a PH Diagram';
            app.PHTDiagramgivenIsothermTemperaturesButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.PHTDiagramgivenIsothermTemperaturesButton.ButtonPushedFcn = createCallbackFcn(app, @PHTDiagramgivenIsothermTemperaturesButtonPushed, true);
            app.PHTDiagramgivenIsothermTemperaturesButton.Text = 'PHT Diagram given Isotherm Temperatures';
            app.ChooseanoptionLabel = uilabel(app.EnthalpyUIFigure);
            app.ChooseanoptionLabel.Text = 'Choose an option';
            app.BackButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.IsobargivenPressureonaTHDiagramButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.IsobargivenPressureonaTHDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @IsobargivenPressureonaTHDiagramButtonPushed, true);
            app.IsobargivenPressureonaTHDiagramButton.Text = 'Isobar given Pressure on a TH Diagram';
            app.TwoPhaseEnvelopeonaTHDiagramButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.TwoPhaseEnvelopeonaTHDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @TwoPhaseEnvelopeonaTHDiagramButtonPushed, true);
            app.TwoPhaseEnvelopeonaTHDiagramButton.Text = 'Two-Phase Envelope on a TH Diagram';
            app.PHTDiagramgivenIsobarPressuresButton = uibutton(app.EnthalpyUIFigure, 'push');
            app.PHTDiagramgivenIsobarPressuresButton.ButtonPushedFcn = createCallbackFcn(app, @PHTDiagramgivenIsobarPressuresButtonPushed, true);
            app.PHTDiagramgivenIsobarPressuresButton.Text = 'PHT Diagram given Isobar Pressures';
            % Show the figure after all components are created
            app.EnthalpyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Enthalpy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnthalpyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnthalpyUIFigure)
        end
    end
end