classdef Enthalpy1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsothermgivenTemperatureonaPHDiagramUIFigure  matlab.ui.Figure
        BackButton                   matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        ReferenceEnthalpy            matlab.ui.control.EditField
        EnthalpykJkgLabel            matlab.ui.control.Label
        ReferencePressure            matlab.ui.control.EditField
        PressurebarEditFieldLabel_2  matlab.ui.control.Label
        ReferenceTemperature         matlab.ui.control.EditField
        TemperatureKLabel            matlab.ui.control.Label
        ReferenceState               matlab.ui.control.EditField
        StateLabel                   matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        IsothermTemperature          matlab.ui.control.DropDown
        IsothermTemperatureKLabel    matlab.ui.control.Label
        Compound                     matlab.ui.control.DropDown
        CompoundDropDownLabel        matlab.ui.control.Label
        EoS                          matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        Table1                       matlab.ui.control.Table
        IsothermgivenTemperatureonaPHDiagramLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalculateButton              matlab.ui.control.Button
        Figure1                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.IsothermgivenTemperatureonaPHDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,11)));
            if ismissing(table2array(data(app.Compound.Value,12))) || string(table2array(data(app.Compound.Value,12)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,12)));
            end
            if ismissing(table2array(data(app.Compound.Value,13))) || string(table2array(data(app.Compound.Value,13)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,13)));
            end
            if ismissing(table2array(data(app.Compound.Value,14))) || string(table2array(data(app.Compound.Value,14)))=="NaN"
                app.ReferenceEnthalpy.Value="NaN";
            else
                app.ReferenceEnthalpy.Value=string(table2array(data(app.Compound.Value,14)));
            end
            % Isotherm Temperature
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,1))) && string(table2array(data2(i,1)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,1)));
                end
            end
            if tam>0
                app.IsothermTemperature.Items=aux;
            else
                app.IsothermTemperature.Items={};
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Enthalpy;
            delete(app);
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isotherm Temperature");
            cla(app.Figure1);
            app.Table1.Data={};
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,11)));
            if ismissing(table2array(data(app.Compound.Value,12))) || string(table2array(data(app.Compound.Value,12)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,12)));
            end
            if ismissing(table2array(data(app.Compound.Value,13))) || string(table2array(data(app.Compound.Value,13)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,13)));
            end
            if ismissing(table2array(data(app.Compound.Value,14))) || string(table2array(data(app.Compound.Value,14)))=="NaN"
                app.ReferenceEnthalpy.Value="NaN";
            else
                app.ReferenceEnthalpy.Value=string(table2array(data(app.Compound.Value,14)));
            end
            % Isotherm Temperature
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,1))) && string(table2array(data2(i,1)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,1)));
                end
            end
            if tam>0
                app.IsothermTemperature.Items=aux;
            else
                app.IsothermTemperature.Items={};
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(convertCharsToStrings(app.IsothermTemperature.Value)) || app.ReferenceState.Value=="NaN" || app.ReferenceTemperature.Value=="NaN" || app.ReferencePressure.Value=="NaN" || app.ReferenceEnthalpy.Value=="NaN"
                msgbox("Incomplete Data.","Data Status","error");
            else
                progressbar=uiprogressdlg(app.IsothermgivenTemperatureonaPHDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Isotherm_given_Temperature_PH_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            end
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isotherm Temperature");
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Value changed function: IsothermTemperature
        function IsothermTemperatureValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isotherm Temperature");
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.IsothermgivenTemperatureonaPHDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)));
            table1.Properties.VariableNames{'Var1'}='Phase';
            table1.Properties.VariableNames{'Var2'}='P [bar]';
            table1.Properties.VariableNames{'Var3'}='Z';
            table1.Properties.VariableNames{'Var4'}='H [kJ/kg]';
            writecell(cell(5000,4),"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A1");
            writetable(table1,"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A8");
            writecell({'Compound'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A2");
            writecell({'Isotherm Temperature [K]'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A3");
            writecell({'Reference State'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A4");
            writecell({'Reference Temperature [K]'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A5");
            writecell({'Reference Pressure [bar]'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A6");
            writecell({'Reference Enthalpy [kJ/kg]'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A7");
            writecell({app.Compound.Value},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B1");
            writecell({app.EoS.Value},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B2");
            writematrix(str2double({app.IsothermTemperature.Value}),"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B3");
            writecell({app.ReferenceState.Value},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B4");
            writematrix(str2double({app.ReferenceTemperature.Value}),"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B5");
            writematrix(str2double({app.ReferencePressure.Value}),"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B6");
            writematrix(str2double({app.ReferenceEnthalpy.Value}),"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B7");
            for i=1:height(table1)
                if string(table2cell(table1(i,1)))=="Satured Liquid (Experimental Data)" 
                    index=i+8;
                    break;
                end
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index,2,index,2])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['B',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index+1,2,index+1,2])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['B',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index,3,index,3])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['C',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index+1,3,index+1,3])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['C',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index,4,index,4])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['D',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index+1,4,index+1,4])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PH Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['D',num2str(index+1)]);
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IsothermgivenTemperatureonaPHDiagramUIFigure = uifigure('Visible', 'off');
            app.IsothermgivenTemperatureonaPHDiagramUIFigure.Name = 'Isotherm given Temperature on a PH Diagram';
            app.IsothermgivenTemperatureonaPHDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound @ Isotherm Temperature')
            xlabel(app.Figure1, 'H [kJ/kg]')
            ylabel(app.Figure1, 'P [bar]')
            zlabel(app.Figure1, 'Z')
            app.CalculateButton = uibutton(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.IsothermgivenTemperatureonaPHDiagramLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.IsothermgivenTemperatureonaPHDiagramLabel.Text = 'Isotherm given Temperature on a PH Diagram';
            app.Table1 = uitable(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.Table1.ColumnName = {'Phase'; 'P [bar]'; 'Z'; 'H [kJ/kg]'};
            app.Table1.RowName = {};
            app.CubicEquationofStateDropDownLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.IsothermTemperatureKLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.IsothermTemperatureKLabel.Text = 'Isotherm Temperature [K]';
            app.IsothermTemperature = uidropdown(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.IsothermTemperature.Items = {};
            app.IsothermTemperature.ValueChangedFcn = createCallbackFcn(app, @IsothermTemperatureValueChanged, true);
            app.IsothermTemperature.Value = {};
            app.Label = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.Label.Text = {'Symbology'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the pressure is less than the triple pressure.'; '**: Thermodynamically not possible case because the pressure is greater than the critical pressure.'};
            app.Label_2 = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.Label_2.Text = 'Reference Data';
            app.StateLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.StateLabel.Text = 'State';
            app.ReferenceState = uieditfield(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'text');
            app.ReferenceState.Editable = 'off';
            app.TemperatureKLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.ReferenceTemperature = uieditfield(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'text');
            app.ReferenceTemperature.Editable = 'off';
            app.PressurebarEditFieldLabel_2 = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.PressurebarEditFieldLabel_2.Text = 'Pressure [bar]';
            app.ReferencePressure = uieditfield(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'text');
            app.ReferencePressure.Editable = 'off';
            app.EnthalpykJkgLabel = uilabel(app.IsothermgivenTemperatureonaPHDiagramUIFigure);
            app.EnthalpykJkgLabel.Text = 'Enthalpy [kJ/kg]';
            app.ReferenceEnthalpy = uieditfield(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'text');
            app.ReferenceEnthalpy.Editable = 'off';
            app.ExportButton = uibutton(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.IsothermgivenTemperatureonaPHDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.IsothermgivenTemperatureonaPHDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Enthalpy1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IsothermgivenTemperatureonaPHDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsothermgivenTemperatureonaPHDiagramUIFigure)
        end
    end
end