classdef Entropy4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsobargivenPressureonaTSDiagramUIFigure  matlab.ui.Figure
        CriticalPressure              matlab.ui.control.EditField
        CalculatedPressurebarLabel_2  matlab.ui.control.Label
        CriticalTemperature           matlab.ui.control.EditField
        TemperatureKLabel_3           matlab.ui.control.Label
        TriplePressure                matlab.ui.control.EditField
        CalculatedPressurebarLabel    matlab.ui.control.Label
        TripleTemperature             matlab.ui.control.EditField
        TemperatureKLabel_2           matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        ReferenceEntropy              matlab.ui.control.EditField
        EnthalpykJkgLabel             matlab.ui.control.Label
        ReferencePressure             matlab.ui.control.EditField
        PressurebarEditFieldLabel_2   matlab.ui.control.Label
        ReferenceTemperature          matlab.ui.control.EditField
        TemperatureKLabel             matlab.ui.control.Label
        ReferenceState                matlab.ui.control.EditField
        StateLabel                    matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        IsobarPressure                matlab.ui.control.DropDown
        IsothermTemperatureKLabel     matlab.ui.control.Label
        Compound                      matlab.ui.control.DropDown
        CompoundDropDownLabel         matlab.ui.control.Label
        EoS                           matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        Table1                        matlab.ui.control.Table
        IsobargivenPressureonaTSDiagramLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CalculateButton               matlab.ui.control.Button
        Figure1                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.IsobargivenPressureonaTSDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,15)));
            if ismissing(table2array(data(app.Compound.Value,16))) || string(table2array(data(app.Compound.Value,16)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,16)));
            end
            if ismissing(table2array(data(app.Compound.Value,17))) || string(table2array(data(app.Compound.Value,17)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,17)));
            end
            if ismissing(table2array(data(app.Compound.Value,18))) || string(table2array(data(app.Compound.Value,18)))=="NaN"
                app.ReferenceEntropy.Value="NaN";
            else
                app.ReferenceEntropy.Value=string(table2array(data(app.Compound.Value,18)));
            end
            % Isobar Pressure
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,2)));
                end
            end
            if tam>0
                app.IsobarPressure.Items=aux;
            else
                app.IsobarPressure.Items={};
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Entropy;
            delete(app);
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isobar Pressure");
            cla(app.Figure1);
            app.Table1.Data={};
            app.CriticalTemperature.Value="";
            app.CriticalPressure.Value="";
            app.TripleTemperature.Value="";
            app.TriplePressure.Value="";
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,15)));
            if ismissing(table2array(data(app.Compound.Value,16))) || string(table2array(data(app.Compound.Value,16)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,16)));
            end
            if ismissing(table2array(data(app.Compound.Value,17))) || string(table2array(data(app.Compound.Value,17)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,17)));
            end
            if ismissing(table2array(data(app.Compound.Value,18))) || string(table2array(data(app.Compound.Value,18)))=="NaN"
                app.ReferenceEntropy.Value="NaN";
            else
                app.ReferenceEntropy.Value=string(table2array(data(app.Compound.Value,18)));
            end
            % Isobar Pressure
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,2)));
                end
            end
            if tam>0
                app.IsobarPressure.Items=aux;
            else
                app.IsobarPressure.Items={};
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(convertCharsToStrings(app.IsobarPressure.Value)) || app.ReferenceState.Value=="NaN" || app.ReferenceTemperature.Value=="NaN" || app.ReferencePressure.Value=="NaN" || app.ReferenceEntropy.Value=="NaN"
                msgbox("Incomplete Data.","Data Status","error");
            else
                progressbar=uiprogressdlg(app.IsobargivenPressureonaTSDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Isobar_given_Pressure_TS_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            end
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isobar Pressure");
            app.CriticalTemperature.Value="";
            app.CriticalPressure.Value="";
            app.TripleTemperature.Value="";
            app.TriplePressure.Value="";
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Value changed function: IsobarPressure
        function IsobarPressureValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isobar Pressure");
            app.CriticalTemperature.Value="";
            app.CriticalPressure.Value="";
            app.TripleTemperature.Value="";
            app.TriplePressure.Value="";
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.IsobargivenPressureonaTSDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)));
            table1.Properties.VariableNames{'Var1'}='Phase';
            table1.Properties.VariableNames{'Var2'}='T [K]';
            table1.Properties.VariableNames{'Var3'}='Z';
            table1.Properties.VariableNames{'Var4'}='S [kJ/kg/K]';
            writecell(cell(5000,4),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A1");
            writetable(table1,"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A12");
            writecell({'Compound'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A2");
            writecell({'Isobar Pressure [bar]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A3");
            writecell({'Reference State'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A4");
            writecell({'Reference Temperature [K]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A5");
            writecell({'Reference Pressure [bar]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A6");
            writecell({'Reference Entropy [kJ/kg/K]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A7");
            writecell({'Triple Temperature [K]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A8");
            writecell({'Calculated Triple Pressure [bar]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A9");
            writecell({'Close to Critical Temperature (CCT) [K]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A10");
            writecell({'Calculated Pressure given CCT [bar]'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","A11");
            writecell({app.Compound.Value},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B1");
            writecell({app.EoS.Value},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B2");
            writematrix(str2double({app.IsobarPressure.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B3");
            writecell({app.ReferenceState.Value},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B4");
            writematrix(str2double({app.ReferenceTemperature.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B5");
            writematrix(str2double({app.ReferencePressure.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B6");
            writematrix(str2double({app.ReferenceEntropy.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B7");
            writematrix(str2double({app.TripleTemperature.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B8");
            writematrix(str2double({app.TriplePressure.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B9");
            writematrix(str2double({app.CriticalTemperature.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B10");
            writematrix(str2double({app.CriticalPressure.Value}),"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range","B11");
            for i=1:height(table1)
                if string(table2cell(table1(i,1)))=="Satured Liquid (Experimental Data)"
                    index=i+12;
                    break;
                end
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index,2,index,2])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index+1,2,index+1,2])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index,3,index,3])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index+1,3,index+1,3])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index,4,index,4])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index+1,4,index+1,4])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-4,2,index-4,2])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index-4)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-3,2,index-3,2])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index-3)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-4,3,index-4,3])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index-4)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-3,3,index-3,3])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index-3)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-4,4,index-4,4])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index-4)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-3,4,index-3,4])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TS Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index-3)]);
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IsobargivenPressureonaTSDiagramUIFigure = uifigure('Visible', 'off');
            app.IsobargivenPressureonaTSDiagramUIFigure.Name = 'Isobar given Pressure on a TS Diagram';
            app.IsobargivenPressureonaTSDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.IsobargivenPressureonaTSDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound @ Isobar Pressure')
            xlabel(app.Figure1, 'S [kJ/kg/K]')
            ylabel(app.Figure1, 'T [K]')
            zlabel(app.Figure1, 'Z')
            app.CalculateButton = uibutton(app.IsobargivenPressureonaTSDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.IsobargivenPressureonaTSDiagramLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.IsobargivenPressureonaTSDiagramLabel.Text = 'Isobar given Pressure on a TS Diagram';
            app.Table1 = uitable(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Table1.ColumnName = {'Phase'; 'T [K]'; 'Z'; 'S [kJ/kg/K]'};
            app.Table1.RowName = {};
            app.CubicEquationofStateDropDownLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.IsothermTemperatureKLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.IsothermTemperatureKLabel.Text = 'Isobar Pressure [bar]';
            app.IsobarPressure = uidropdown(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.IsobarPressure.Items = {};
            app.IsobarPressure.ValueChangedFcn = createCallbackFcn(app, @IsobarPressureValueChanged, true);
            app.IsobarPressure.Value = {};
            app.Label_2 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Label_2.Text = 'Reference Data';
            app.StateLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.StateLabel.Text = 'State';
            app.ReferenceState = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.ReferenceState.Editable = 'off';
            app.TemperatureKLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.ReferenceTemperature = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.ReferenceTemperature.Editable = 'off';
            app.PressurebarEditFieldLabel_2 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.PressurebarEditFieldLabel_2.Text = 'Pressure [bar]';
            app.ReferencePressure = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.ReferencePressure.Editable = 'off';
            app.EnthalpykJkgLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.EnthalpykJkgLabel.Text = 'Entropy [kJ/kg/K]';
            app.ReferenceEntropy = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.ReferenceEntropy.Editable = 'off';
            app.ExportButton = uibutton(app.IsobargivenPressureonaTSDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.IsobargivenPressureonaTSDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Label_3 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Label_3.Text = {'Symbology'; 'NaN: Not a Number.'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the temperature is less than the triple temperature.'; '**: Thermodynamically not possible case because the temperature is greater than the critical temperature.'};
            app.Label_4 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Label_4.Text = 'Triple Point';
            app.Label_5 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.Label_5.Text = 'Close to Critical Point';
            app.TemperatureKLabel_2 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.TemperatureKLabel_2.Text = 'Temperature [K]';
            app.TripleTemperature = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.TripleTemperature.Editable = 'off';
            app.CalculatedPressurebarLabel = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.CalculatedPressurebarLabel.Text = 'Calculated Pressure [bar]';
            app.TriplePressure = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.TriplePressure.Editable = 'off';
            app.TemperatureKLabel_3 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.TemperatureKLabel_3.Text = 'Temperature [K]';
            app.CriticalTemperature = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.CriticalTemperature.Editable = 'off';
            app.CalculatedPressurebarLabel_2 = uilabel(app.IsobargivenPressureonaTSDiagramUIFigure);
            app.CalculatedPressurebarLabel_2.Text = 'Calculated Pressure [bar]';
            app.CriticalPressure = uieditfield(app.IsobargivenPressureonaTSDiagramUIFigure, 'text');
            app.CriticalPressure.Editable = 'off';
            % Show the figure after all components are created
            app.IsobargivenPressureonaTSDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entropy4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IsobargivenPressureonaTSDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsobargivenPressureonaTSDiagramUIFigure)
        end
    end
end