classdef Volume1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsothermgivenTemperatureonaPVDiagramUIFigure  matlab.ui.Figure
        Label                      matlab.ui.control.Label
        BackButton                 matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        Table3                     matlab.ui.control.Table
        Table2                     matlab.ui.control.Table
        Table1                     matlab.ui.control.Table
        IsothermgivenTemperatureonaPVDiagramLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        CalculateButton            matlab.ui.control.Button
        IsothermTemperature        matlab.ui.control.DropDown
        IsothermTemperatureKLabel  matlab.ui.control.Label
        Compound                   matlab.ui.control.DropDown
        CompoundDropDownLabel      matlab.ui.control.Label
        EoS                        matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        Figure3                    matlab.ui.control.UIAxes
        Figure2                    matlab.ui.control.UIAxes
        Figure1                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.IsothermgivenTemperatureonaPVDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Isotherm Temperature
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,1))) && string(table2array(data2(i,1)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,1)));
                end
            end
            if tam>0
                app.IsothermTemperature.Items=aux;
            else
                app.IsothermTemperature.Items={};
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(convertCharsToStrings(app.IsothermTemperature.Value))
                msgbox("Incomplete Data.","Data Status","error");
            else
                progressbar=uiprogressdlg(app.IsothermgivenTemperatureonaPVDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Isotherm_given_Temperature_PV_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            end
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isotherm Temperature");
            cla(app.Figure1);
            cla(app.Figure2);
            cla(app.Figure3);
            app.Table1.Data={};
            app.Table2.Data={};
            app.Table3.Data={};
            % Isotherm Temperature
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,1))) && string(table2array(data2(i,1)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,1)));
                end
            end
            if tam>0
                app.IsothermTemperature.Items=aux;
            else
                app.IsothermTemperature.Items={};
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Volume;
            delete(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.IsothermgivenTemperatureonaPVDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)));
            table1.Properties.VariableNames{'Var1'}='Phase';
            table1.Properties.VariableNames{'Var2'}='P [bar]';
            table1.Properties.VariableNames{'Var3'}='Z';
            table1.Properties.VariableNames{'Var4'}='V [m3/kg]';
            aux=app.Table2.Data;
            table2=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)));
            table2.Properties.VariableNames{'Var1'}='Initial Point';
            table2.Properties.VariableNames{'Var2'}='Iteration Number';
            table2.Properties.VariableNames{'Var3'}='P [bar]';
            aux=app.Table3.Data;
            table3=table(str2double(aux(:,1)),str2double(aux(:,2)),str2double(aux(:,3)));
            table3.Properties.VariableNames{'Var1'}='P [bar]';
            table3.Properties.VariableNames{'Var2'}='fliquid [bar]';
            table3.Properties.VariableNames{'Var3'}='fvapor [bar]';
            writecell(cell(5000,4),"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A1");
            writetable(table1,"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A4");
            writecell({'Compound'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A2");
            writecell({'Isotherm Temperature [K]'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","A3");
            writecell({app.Compound.Value},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B1");
            writecell({app.EoS.Value},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B2");
            writematrix(str2double({app.IsothermTemperature.Value}),"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range","B3");
            for i=1:height(table1)
                if string(table2cell(table1(i,1)))=="Satured Liquid (Experimental Data)" 
                    index=i+4;
                    break;
                end
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index,2,index,2])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['B',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index+1,2,index+1,2])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['B',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index,3,index,3])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['C',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index+1,3,index+1,3])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['C',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index,4,index,4])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['D',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",[index+1,4,index+1,4])))
                writecell({'NA'},"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Isotherm given Temperature","Range",['D',num2str(index+1)]);
            end
            writecell(cell(5000,3),"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Pressure vs Iteration Number","Range","A1");
            writetable(table2,"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Pressure vs Iteration Number");
            writecell(cell(5000,3),"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Fugacity vs Pressure","Range","A1");
            writetable(table3,"./Results/Isotherm given Temperature on a PV Diagram.xlsx","Sheet","Fugacity vs Pressure");
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isotherm Temperature");
            cla(app.Figure1);
            cla(app.Figure2);
            cla(app.Figure3);
            app.Table1.Data={};
            app.Table2.Data={};
            app.Table3.Data={};
        end

        % Value changed function: IsothermTemperature
        function IsothermTemperatureValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isotherm Temperature");
            cla(app.Figure1);
            cla(app.Figure2);
            cla(app.Figure3);
            app.Table1.Data={};
            app.Table2.Data={};
            app.Table3.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IsothermgivenTemperatureonaPVDiagramUIFigure = uifigure('Visible', 'off');
            app.IsothermgivenTemperatureonaPVDiagramUIFigure.Name = 'Isotherm given Temperature on a PV Diagram';
            app.IsothermgivenTemperatureonaPVDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound @ Isotherm Temperature')
            xlabel(app.Figure1, 'V [m^3/kg]')
            ylabel(app.Figure1, 'P [bar]')
            zlabel(app.Figure1, 'Z')
            app.Figure1.XScale = 'log';
            app.Figure2 = uiaxes(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            title(app.Figure2, 'Fugacity Test - Pressure vs Iteration Number')
            xlabel(app.Figure2, 'Iteration Number')
            ylabel(app.Figure2, 'Pressure [bar]')
            zlabel(app.Figure2, 'Z')
            app.Figure3 = uiaxes(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            title(app.Figure3, 'Fugacity vs Pressure')
            xlabel(app.Figure3, 'Pressure [bar]')
            ylabel(app.Figure3, 'Fugacity [bar]')
            zlabel(app.Figure3, 'Z')
            app.CubicEquationofStateDropDownLabel = uilabel(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.IsothermTemperatureKLabel = uilabel(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.IsothermTemperatureKLabel.Text = 'Isotherm Temperature [K]';
            app.IsothermTemperature = uidropdown(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.IsothermTemperature.Items = {};
            app.IsothermTemperature.ValueChangedFcn = createCallbackFcn(app, @IsothermTemperatureValueChanged, true);
            app.IsothermTemperature.Value = {};
            app.CalculateButton = uibutton(app.IsothermgivenTemperatureonaPVDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.IsothermgivenTemperatureonaPVDiagramLabel = uilabel(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.IsothermgivenTemperatureonaPVDiagramLabel.Text = 'Isotherm given Temperature on a PV Diagram';
            app.Table1 = uitable(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.Table1.ColumnName = {'Phase'; 'P [bar]'; 'Z'; 'V [m3/kg]'};
            app.Table1.RowName = {};
            app.Table2 = uitable(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.Table2.ColumnName = {'Initial Point'; 'Iteration Number'; 'P [bar]'};
            app.Table2.RowName = {};
            app.Table3 = uitable(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.Table3.ColumnName = {'P [bar]'; 'fliquid  [bar]'; 'fvapor [bar]'};
            app.Table3.RowName = {};
            app.ExportButton = uibutton(app.IsothermgivenTemperatureonaPVDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.IsothermgivenTemperatureonaPVDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Label = uilabel(app.IsothermgivenTemperatureonaPVDiagramUIFigure);
            app.Label.Text = {'Symbology'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the pressure is less than the triple pressure.'; '**: Thermodynamically not possible case because the pressure is greater than the critical pressure.'};
            % Show the figure after all components are created
            app.IsothermgivenTemperatureonaPVDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IsothermgivenTemperatureonaPVDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsothermgivenTemperatureonaPVDiagramUIFigure)
        end
    end
end