classdef Volume4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CriticalTemperature          matlab.ui.control.EditField
        CriticalTemperatureKLabel_2  matlab.ui.control.Label
        TitleFigure1                 matlab.ui.control.Label
        TripleTemperature            matlab.ui.control.EditField
        TripleTemperatureKLabel      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Table1                       matlab.ui.control.Table
        Table2PR                     matlab.ui.control.Table
        Table2SRK                    matlab.ui.control.Table
        Table2RK                     matlab.ui.control.Table
        Compound                     matlab.ui.control.DropDown
        CompoundDropDownLabel        matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        Table2vdW                    matlab.ui.control.Table
        PsatgivenTapplyingDifferentsBracketingMethods  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalculateButton              matlab.ui.control.Button
        Figure1PR                    matlab.ui.control.UIAxes
        Figure1SRK                   matlab.ui.control.UIAxes
        Figure1RK                    matlab.ui.control.UIAxes
        Figure2                      matlab.ui.control.UIAxes
        Figure1vdW                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.UIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Volume;
            delete(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Saturation Pressures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,2)));
                end
            end
            if tam>0
                progressbar=uiprogressdlg(app.UIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Saturation_Pressure_given_Temperature;
                app.ExportButton.Visible="on";
                app.TitleFigure1.Text=strcat(app.Compound.Value," - Iterations vs Saturation Pressure");
                close(progressbar);
            else
                msgbox("Input saturation pressures not found.","Data Status","error");
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.UIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)),str2double(aux(:,8)));
            table1.Properties.VariableNames{'Var1'}='EoS';
            table1.Properties.VariableNames{'Var2'}='Calculated Triple Pressure [bar]';
            table1.Properties.VariableNames{'Var3'}='Calculated Pressure given CCT [bar]';
            table1.Properties.VariableNames{'Var4'}='Total Iterations-B';
            table1.Properties.VariableNames{'Var5'}='Total Iterations-FP';
            table1.Properties.VariableNames{'Var6'}='Total Iterations-I';
            table1.Properties.VariableNames{'Var7'}='Total Iterations-P';
            table1.Properties.VariableNames{'Var8'}='Total Iterations-AB';
            aux=app.Table2vdW.Data;
            table2vdW=table(str2double(aux(:,1)),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)),str2double(aux(:,8)),str2double(aux(:,9)),str2double(aux(:,10)),str2double(aux(:,11)));
            table2vdW.Properties.VariableNames{'Var1'}='Psat [bar]';
            table2vdW.Properties.VariableNames{'Var2'}='Tsat-B [K]';
            table2vdW.Properties.VariableNames{'Var3'}='Iterations-B';
            table2vdW.Properties.VariableNames{'Var4'}='Tsat-FP [K]';
            table2vdW.Properties.VariableNames{'Var5'}='Iterations-FP';
            table2vdW.Properties.VariableNames{'Var6'}='Tsat-I [K]';
            table2vdW.Properties.VariableNames{'Var7'}='Iterations-I';
            table2vdW.Properties.VariableNames{'Var8'}='Tsat-P [K]';
            table2vdW.Properties.VariableNames{'Var9'}='Iterations-P';
            table2vdW.Properties.VariableNames{'Var10'}='Tsat-AB [K]';
            table2vdW.Properties.VariableNames{'Var11'}='Iterations-AB';
            aux=app.Table2RK.Data;
            table2RK=table(str2double(aux(:,1)),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)),str2double(aux(:,8)),str2double(aux(:,9)),str2double(aux(:,10)),str2double(aux(:,11)));
            table2RK.Properties.VariableNames{'Var1'}='Psat [bar]';
            table2RK.Properties.VariableNames{'Var2'}='Tsat-B [K]';
            table2RK.Properties.VariableNames{'Var3'}='Iterations-B';
            table2RK.Properties.VariableNames{'Var4'}='Tsat-FP [K]';
            table2RK.Properties.VariableNames{'Var5'}='Iterations-FP';
            table2RK.Properties.VariableNames{'Var6'}='Tsat-I [K]';
            table2RK.Properties.VariableNames{'Var7'}='Iterations-I';
            table2RK.Properties.VariableNames{'Var8'}='Tsat-P [K]';
            table2RK.Properties.VariableNames{'Var9'}='Iterations-P';
            table2RK.Properties.VariableNames{'Var10'}='Tsat-AB [K]';
            table2RK.Properties.VariableNames{'Var11'}='Iterations-AB';
            aux=app.Table2SRK.Data;
            table2SRK=table(str2double(aux(:,1)),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)),str2double(aux(:,8)),str2double(aux(:,9)),str2double(aux(:,10)),str2double(aux(:,11)));
            table2SRK.Properties.VariableNames{'Var1'}='Psat [bar]';
            table2SRK.Properties.VariableNames{'Var2'}='Tsat-B [K]';
            table2SRK.Properties.VariableNames{'Var3'}='Iterations-B';
            table2SRK.Properties.VariableNames{'Var4'}='Tsat-FP [K]';
            table2SRK.Properties.VariableNames{'Var5'}='Iterations-FP';
            table2SRK.Properties.VariableNames{'Var6'}='Tsat-I [K]';
            table2SRK.Properties.VariableNames{'Var7'}='Iterations-I';
            table2SRK.Properties.VariableNames{'Var8'}='Tsat-P [K]';
            table2SRK.Properties.VariableNames{'Var9'}='Iterations-P';
            table2SRK.Properties.VariableNames{'Var10'}='Tsat-AB [K]';
            table2SRK.Properties.VariableNames{'Var11'}='Iterations-AB';
            aux=app.Table2PR.Data;
            table2PR=table(str2double(aux(:,1)),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)),str2double(aux(:,8)),str2double(aux(:,9)),str2double(aux(:,10)),str2double(aux(:,11)));
            table2PR.Properties.VariableNames{'Var1'}='Psat [bar]';
            table2PR.Properties.VariableNames{'Var2'}='Tsat-B [K]';
            table2PR.Properties.VariableNames{'Var3'}='Iterations-B';
            table2PR.Properties.VariableNames{'Var4'}='Tsat-FP [K]';
            table2PR.Properties.VariableNames{'Var5'}='Iterations-FP';
            table2PR.Properties.VariableNames{'Var6'}='Tsat-I [K]';
            table2PR.Properties.VariableNames{'Var7'}='Iterations-I';
            table2PR.Properties.VariableNames{'Var8'}='Tsat-P [K]';
            table2PR.Properties.VariableNames{'Var9'}='Iterations-P';
            table2PR.Properties.VariableNames{'Var10'}='Tsat-AB [K]';
            table2PR.Properties.VariableNames{'Var11'}='Iterations-AB';
            writecell(cell(10,8),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","A1");
            writetable(table1,"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","A4");
            writecell({'Compound'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","A1");
            writecell({'Triple Temperature [K]'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","A2");
            writecell({'Close to Critical Temperature (CCT) [K]'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","A3");
            writecell({app.Compound.Value},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","B1");
            writematrix(str2double({app.TripleTemperature.Value}),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","B2");
            writematrix(str2double({app.CriticalTemperature.Value}),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","Total Iterations vs EoS","Range","B3");
            writecell(cell(5000,11),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range","A1");
            writetable(table2vdW,"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat");
            for i=2:1+height(table2vdW)
                if ismissing(string(readcell("./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",[i,2,i,2])))
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['B',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['C',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['D',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['E',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['F',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['G',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['H',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['I',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['J',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","vdW - Iterations vs Psat","Range",['K',num2str(i)]);
                end
            end
            writecell(cell(5000,11),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range","A1");
            writetable(table2RK,"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat");
            for i=2:1+height(table2RK)
                if ismissing(string(readcell("./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",[i,2,i,2])))
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['B',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['C',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['D',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['E',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['F',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['G',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['H',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['I',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['J',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","RK - Iterations vs Psat","Range",['K',num2str(i)]);
                end
            end
            writecell(cell(5000,11),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range","A1");
            writetable(table2SRK,"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat");
            for i=2:1+height(table2SRK)
                if ismissing(string(readcell("./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",[i,2,i,2])))
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['B',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['C',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['D',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['E',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['F',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['G',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['H',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['I',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['J',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","SRK - Iterations vs Psat","Range",['K',num2str(i)]);
                end
            end
            writecell(cell(5000,11),"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range","A1");
            writetable(table2PR,"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat");
            for i=2:1+height(table2PR)
                if ismissing(string(readcell("./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",[i,2,i,2])))
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['B',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['C',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['D',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['E',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['F',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['G',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['H',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['I',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['J',num2str(i)]);
                    writecell({'NaN'},"./Results/Saturation Pressure given Temperature applying Differents Bracketing Methods.xlsx","Sheet","PR - Iterations vs Psat","Range",['K',num2str(i)]);
                end
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            app.TitleFigure1.Text="Compound - Iterations vs Saturation Pressure";
            app.TripleTemperature.Value="";
            app.CriticalTemperature.Value="";
            cla(app.Figure1vdW);
            cla(app.Figure1RK);
            cla(app.Figure1SRK);
            cla(app.Figure1PR);
            cla(app.Figure2);
            app.Table1.Data={};
            app.Table2vdW.Data={};
            app.Table2RK.Data={};
            app.Table2SRK.Data={};
            app.Table2PR.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Saturation Pressure given Temperature applying Differents Bracketing Methods';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1vdW = uiaxes(app.UIFigure);
            title(app.Figure1vdW, 'van der Waals (vdW)')
            xlabel(app.Figure1vdW, 'Saturation Pressure [bar]')
            ylabel(app.Figure1vdW, 'Iterations')
            zlabel(app.Figure1vdW, 'Z')
            app.Figure1vdW.YScale = 'log';
            app.Figure2 = uiaxes(app.UIFigure);
            title(app.Figure2, 'Total Iterations vs Cubic Equation of State')
            xlabel(app.Figure2, 'Cubic Equation of State')
            ylabel(app.Figure2, 'Total Iterations')
            zlabel(app.Figure2, 'Z')
            app.Figure2.YScale = 'log';
            app.Figure1RK = uiaxes(app.UIFigure);
            title(app.Figure1RK, 'Redlich-Kwong (RK)')
            xlabel(app.Figure1RK, 'Saturation Pressure [bar]')
            ylabel(app.Figure1RK, 'Iterations')
            zlabel(app.Figure1RK, 'Z')
            app.Figure1RK.YScale = 'log';
            app.Figure1SRK = uiaxes(app.UIFigure);
            title(app.Figure1SRK, 'Soave-Redlich-Kwong (SRK)')
            xlabel(app.Figure1SRK, 'Saturation Pressure [bar]')
            ylabel(app.Figure1SRK, 'Iterations')
            zlabel(app.Figure1SRK, 'Z')
            app.Figure1SRK.YScale = 'log';
            app.Figure1PR = uiaxes(app.UIFigure);
            title(app.Figure1PR, 'Peng-Robinson (PR)')
            xlabel(app.Figure1PR, 'Saturation Pressure [bar]')
            ylabel(app.Figure1PR, 'Iterations')
            zlabel(app.Figure1PR, 'Z')
            app.Figure1PR.YScale = 'log';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.PsatgivenTapplyingDifferentsBracketingMethods = uilabel(app.UIFigure);
            app.PsatgivenTapplyingDifferentsBracketingMethods.Text = {'Saturation Pressure '; 'given Temperature applying Differents Bracketing Methods'};
            app.Table2vdW = uitable(app.UIFigure);
            app.Table2vdW.ColumnName = {'Psat [bar]'; 'Tsat-B [K]'; 'Iterations-B'; 'Tsat-FP [K]'; 'Iterations-FP'; 'Tsat-I [K]'; 'Iterations-I'; 'Tsat-P [K]'; 'Iterations-P'; 'Tsat-AB [K]'; 'Iterations-AB'};
            app.Table2vdW.RowName = {};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Symbology'; 'NaN: Not a Number.'};
            app.CompoundDropDownLabel = uilabel(app.UIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.UIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.Table2RK = uitable(app.UIFigure);
            app.Table2RK.ColumnName = {'Psat [bar]'; 'Tsat-B [K]'; 'Iterations-B'; 'Tsat-FP [K]'; 'Iterations-FP'; 'Tsat-I [K]'; 'Iterations-I'; 'Tsat-P [K]'; 'Iterations-P'; 'Tsat-AB [K]'; 'Iterations-AB'};
            app.Table2RK.RowName = {};
            app.Table2SRK = uitable(app.UIFigure);
            app.Table2SRK.ColumnName = {'Psat [bar]'; 'Tsat-B [K]'; 'Iterations-B'; 'Tsat-FP [K]'; 'Iterations-FP'; 'Tsat-I [K]'; 'Iterations-I'; 'Tsat-P [K]'; 'Iterations-P'; 'Tsat-AB [K]'; 'Iterations-AB'};
            app.Table2SRK.RowName = {};
            app.Table2PR = uitable(app.UIFigure);
            app.Table2PR.ColumnName = {'Psat [bar]'; 'Tsat-B [K]'; 'Iterations-B'; 'Tsat-FP [K]'; 'Iterations-FP'; 'Tsat-I [K]'; 'Iterations-I'; 'Tsat-P [K]'; 'Iterations-P'; 'Tsat-AB [K]'; 'Iterations-AB'};
            app.Table2PR.RowName = {};
            app.Table1 = uitable(app.UIFigure);
            app.Table1.ColumnName = {'EoS'; 'Calculated Triple Pressure [bar]'; 'Calculated Pressure given CCT [bar]'; 'Total Iterations-B'; 'Total Iterations-FP'; 'Total Iterations-I'; 'Total Iterations-P'; 'Total Iterations-AB'};
            app.Table1.RowName = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'B: Bisection                                                 FP: False Position                                                   I: Illinois'; '                                   P: Pegasus                                                         AB: Anderson-Björck'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Bracketing Methods';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'vdW';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'RK';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'SRK';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'PR';
            app.TripleTemperatureKLabel = uilabel(app.UIFigure);
            app.TripleTemperatureKLabel.Text = 'Triple Temperature [K]';
            app.TripleTemperature = uieditfield(app.UIFigure, 'text');
            app.TripleTemperature.Editable = 'off';
            app.TitleFigure1 = uilabel(app.UIFigure);
            app.TitleFigure1.Text = 'Compound - Iterations vs Saturation Pressure';
            app.CriticalTemperatureKLabel_2 = uilabel(app.UIFigure);
            app.CriticalTemperatureKLabel_2.Text = 'Close to Critical Temperature (CCT) [K]';
            app.CriticalTemperature = uieditfield(app.UIFigure, 'text');
            app.CriticalTemperature.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end