classdef Volume5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsobargivenPressureonaTVDiagramUIFigure  matlab.ui.Figure
        CriticalPressure              matlab.ui.control.EditField
        CalculatedPressurebarLabel_2  matlab.ui.control.Label
        CriticalTemperature           matlab.ui.control.EditField
        TemperatureKLabel_2           matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        TriplePressure                matlab.ui.control.EditField
        CalculatedPressurebarLabel    matlab.ui.control.Label
        TripleTemperature             matlab.ui.control.EditField
        TemperatureKLabel             matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        IsobarPressure                matlab.ui.control.DropDown
        IsothermTemperatureKLabel     matlab.ui.control.Label
        Compound                      matlab.ui.control.DropDown
        CompoundDropDownLabel         matlab.ui.control.Label
        EoS                           matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        Table1                        matlab.ui.control.Table
        ExportButton                  matlab.ui.control.Button
        Table2                        matlab.ui.control.Table
        IsobargivenPressureonaTVDiagramLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CalculateButton               matlab.ui.control.Button
        Figure3                       matlab.ui.control.UIAxes
        Figure2                       matlab.ui.control.UIAxes
        Figure1                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.IsobargivenPressureonaTVDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Isobar Pressure
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,2)));
                end
            end
            if tam>0
                app.IsobarPressure.Items=aux;
            else
                app.IsobarPressure.Items={};
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Volume;
            delete(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(convertCharsToStrings(app.IsobarPressure.Value))
                msgbox("Incomplete Data.","Data Status","error");
            else
                progressbar=uiprogressdlg(app.IsobargivenPressureonaTVDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Isobar_given_Pressure_TV_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            end
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isobar Pressure");
            app.CriticalTemperature.Value="";
            app.CriticalPressure.Value="";
            app.TripleTemperature.Value="";
            app.TriplePressure.Value="";
            cla(app.Figure1);
            cla(app.Figure2);
            cla(app.Figure3);
            app.Table1.Data={};
            app.Table2.Data={};
            % Isobar Pressure
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            aux=strings;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    aux(tam)=string(table2array(data2(i,2)));
                end
            end
            if tam>0
                app.IsobarPressure.Items=aux;
            else
                app.IsobarPressure.Items={};
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.IsobargivenPressureonaTVDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)));
            table1.Properties.VariableNames{'Var1'}='Phase';
            table1.Properties.VariableNames{'Var2'}='T [K]';
            table1.Properties.VariableNames{'Var3'}='Z';
            table1.Properties.VariableNames{'Var4'}='V [m3/kg]';
            writecell(cell(5000,4),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","A1");
            writetable(table1,"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","A4");
            writecell({'Compound'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","A2");
            writecell({'Isobar Pressure [bar]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","A3");
            writecell({app.Compound.Value},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","B1");
            writecell({app.EoS.Value},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","B2");
            writematrix(str2double({app.IsobarPressure.Value}),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range","B3");
            for i=1:height(table1)
                if string(table2cell(table1(i,1)))=="Satured Liquid (Experimental Data)"
                    index=i+4;
                    break;
                end
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index,2,index,2])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index+1,2,index+1,2])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index,3,index,3])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index+1,3,index+1,3])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index,4,index,4])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index+1,4,index+1,4])))
                writecell({'NA'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index+1)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-4,2,index-4,2])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index-4)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-3,2,index-3,2])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['B',num2str(index-3)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-4,3,index-4,3])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index-4)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-3,3,index-3,3])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['C',num2str(index-3)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-4,4,index-4,4])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index-4)]);
            end
            if ismissing(string(readcell("./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",[index-3,4,index-3,4])))
                writecell({'NaN'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Isobar given Pressure","Range",['D',num2str(index-3)]);
            end
            writecell(cell(5000,3),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A1");
            writecell({'Triple Temperature [K]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A1");
            writecell({'Calculated Triple Pressure [bar]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A2");
            writecell({'Close to Critical Temperature (CCT) [K]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A3");
            writecell({'Calculated Pressure given CCT [bar]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A4");
            writematrix(str2double({app.TripleTemperature.Value}),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","B1");
            writematrix(str2double({app.TriplePressure.Value}),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","B2");
            writematrix(str2double({app.CriticalTemperature.Value}),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","B3");
            writematrix(str2double({app.CriticalPressure.Value}),"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","B4");
            if ~isempty(app.Table2.Data)
                aux=app.Table2.Data;
                table2=table(str2double(aux(:,1)),str2double(aux(:,2)),str2double(aux(:,3)));
                table2.Properties.VariableNames{'Var1'}='Iteration Number';
                table2.Properties.VariableNames{'Var2'}='Tsat [K]';
                table2.Properties.VariableNames{'Var3'}='Psat [bar]';
                writetable(table2,"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A5");
            else
                writecell({'Iteration Number'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","A5");
                writecell({'Tsat [K]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","B5");
                writecell({'Psat [bar]'},"./Results/Isobar given Pressure on a TV Diagram.xlsx","Sheet","Pegasus Method","Range","C5");
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isobar Pressure");
            app.CriticalTemperature.Value="";
            app.CriticalPressure.Value="";
            app.TripleTemperature.Value="";
            app.TriplePressure.Value="";
            cla(app.Figure1);
            cla(app.Figure2);
            cla(app.Figure3);
            app.Table1.Data={};
            app.Table2.Data={};
        end

        % Value changed function: IsobarPressure
        function IsobarPressureValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound @ Isobar Pressure");
            app.CriticalTemperature.Value="";
            app.CriticalPressure.Value="";
            app.TripleTemperature.Value="";
            app.TriplePressure.Value="";
            cla(app.Figure1);
            cla(app.Figure2);
            cla(app.Figure3);
            app.Table1.Data={};
            app.Table2.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IsobargivenPressureonaTVDiagramUIFigure = uifigure('Visible', 'off');
            app.IsobargivenPressureonaTVDiagramUIFigure.Name = 'Isobar given Pressure on a TV Diagram';
            app.IsobargivenPressureonaTVDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.IsobargivenPressureonaTVDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound @ Isobar Pressure')
            xlabel(app.Figure1, 'V [m^3/kg]')
            ylabel(app.Figure1, 'T [K]')
            zlabel(app.Figure1, 'Z')
            app.Figure1.XScale = 'log';
            app.Figure2 = uiaxes(app.IsobargivenPressureonaTVDiagramUIFigure);
            title(app.Figure2, 'Saturation Pressure vs Saturation Temperature')
            xlabel(app.Figure2, 'Saturation Temperature [K]')
            ylabel(app.Figure2, 'Saturation Pressure [bar]')
            zlabel(app.Figure2, 'Z')
            app.Figure3 = uiaxes(app.IsobargivenPressureonaTVDiagramUIFigure);
            title(app.Figure3, 'Saturation Temperature vs Iteration Number')
            xlabel(app.Figure3, 'Iteration Number')
            ylabel(app.Figure3, 'Saturation Temperature [K]')
            zlabel(app.Figure3, 'Z')
            app.CalculateButton = uibutton(app.IsobargivenPressureonaTVDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.IsobargivenPressureonaTVDiagramLabel = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.IsobargivenPressureonaTVDiagramLabel.Text = 'Isobar given Pressure on a TV Diagram';
            app.Table2 = uitable(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Table2.ColumnName = {'Iteration Number'; 'Tsat [K]'; 'Psat [bar]'};
            app.Table2.RowName = {};
            app.ExportButton = uibutton(app.IsobargivenPressureonaTVDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.Table1 = uitable(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Table1.ColumnName = {'Phase'; 'T [K]'; 'Z'; 'V [m3/kg]'};
            app.Table1.RowName = {};
            app.BackButton = uibutton(app.IsobargivenPressureonaTVDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Label = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Label.Text = {'Symbology'; 'NaN: Not a Number.'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the temperature is less than the triple temperature.'; '**: Thermodynamically not possible case because the temperature is greater than the critical temperature.'};
            app.CubicEquationofStateDropDownLabel = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.IsothermTemperatureKLabel = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.IsothermTemperatureKLabel.Text = 'Isobar Pressure [bar]';
            app.IsobarPressure = uidropdown(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.IsobarPressure.Items = {};
            app.IsobarPressure.ValueChangedFcn = createCallbackFcn(app, @IsobarPressureValueChanged, true);
            app.IsobarPressure.Value = {};
            app.Label_2 = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Label_2.Text = 'Triple Point';
            app.TemperatureKLabel = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.TripleTemperature = uieditfield(app.IsobargivenPressureonaTVDiagramUIFigure, 'text');
            app.TripleTemperature.Editable = 'off';
            app.CalculatedPressurebarLabel = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.CalculatedPressurebarLabel.Text = 'Calculated Pressure [bar]';
            app.TriplePressure = uieditfield(app.IsobargivenPressureonaTVDiagramUIFigure, 'text');
            app.TriplePressure.Editable = 'off';
            app.Label_3 = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.Label_3.Text = 'Close to Critical Point';
            app.TemperatureKLabel_2 = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.TemperatureKLabel_2.Text = 'Temperature [K]';
            app.CriticalTemperature = uieditfield(app.IsobargivenPressureonaTVDiagramUIFigure, 'text');
            app.CriticalTemperature.Editable = 'off';
            app.CalculatedPressurebarLabel_2 = uilabel(app.IsobargivenPressureonaTVDiagramUIFigure);
            app.CalculatedPressurebarLabel_2.Text = 'Calculated Pressure [bar]';
            app.CriticalPressure = uieditfield(app.IsobargivenPressureonaTVDiagramUIFigure, 'text');
            app.CriticalPressure.Editable = 'off';
            % Show the figure after all components are created
            app.IsobargivenPressureonaTVDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IsobargivenPressureonaTVDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsobargivenPressureonaTVDiagramUIFigure)
        end
    end
end