classdef Volume6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TwoPhaseEnvelopeonaTVDiagramUIFigure  matlab.ui.Figure
        CriticalVolume            matlab.ui.control.EditField
        Volumem3kgEditFieldLabel  matlab.ui.control.Label
        CriticalTemperature       matlab.ui.control.EditField
        TemperatureKLabel         matlab.ui.control.Label
        Compound                  matlab.ui.control.DropDown
        CompoundDropDownLabel     matlab.ui.control.Label
        EoS                       matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        BackButton                matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Table1                    matlab.ui.control.Table
        TwoPhaseEnvelopeonaTVDiagramLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        CalculateButton           matlab.ui.control.Button
        Figure1                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.TwoPhaseEnvelopeonaTVDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isobar Pressures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0
                progressbar=uiprogressdlg(app.TwoPhaseEnvelopeonaTVDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Two_Phase_TV_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            else
                msgbox("Input isobar pressures not found.","Data Status","error");
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Volume;
            delete(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.TwoPhaseEnvelopeonaTVDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)));
            table1.Properties.VariableNames{'Var1'}='Method';
            table1.Properties.VariableNames{'Var2'}='Psat [bar]';
            table1.Properties.VariableNames{'Var3'}='Tsat [K]';
            table1.Properties.VariableNames{'Var4'}='Zliquid';
            table1.Properties.VariableNames{'Var5'}='Zvapor';
            table1.Properties.VariableNames{'Var6'}='Vliquid [m3/kg]';
            table1.Properties.VariableNames{'Var7'}='Vvapor [m3/kg]';
            writecell(cell(5000,7),"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","A1");
            writetable(table1,"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","A5");
            writecell({'Compound'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","A2");
            writecell({'Critical Temperature [K]'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","A3");
            writecell({'Critical Volume [m3/kg]'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","A4");
            writecell({app.Compound.Value},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","B1");
            writecell({app.EoS.Value},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","B2");
            writematrix(str2double({app.CriticalTemperature.Value}),"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","B3");
            writematrix(str2double({app.CriticalVolume.Value}),"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range","B4");
            for i=8:3:5+height(table1)
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i,3,i,3])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['C',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i,4,i,4])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['D',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i,5,i,5])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['E',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i,6,i,6])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['F',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i,7,i,7])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['G',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i-2,3,i-2,3])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['C',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i-2,4,i-2,4])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['D',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i-2,5,i-2,5])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['E',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i-2,6,i-2,6])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['F',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",[i-2,7,i-2,7])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TV Diagram.xlsx","Sheet","TV Diagram","Range",['G',num2str(i-2)]);
                end
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound");
            app.CriticalTemperature.Value="";
            app.CriticalVolume.Value="";
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound");
            app.CriticalTemperature.Value="";
            app.CriticalVolume.Value="";
            cla(app.Figure1);
            app.Table1.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TwoPhaseEnvelopeonaTVDiagramUIFigure = uifigure('Visible', 'off');
            app.TwoPhaseEnvelopeonaTVDiagramUIFigure.Name = 'Two-Phase Envelope on a TV Diagram';
            app.TwoPhaseEnvelopeonaTVDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'V [m^3/kg]')
            ylabel(app.Figure1, 'T_{sat} [K]')
            zlabel(app.Figure1, 'Z')
            app.Figure1.XScale = 'log';
            app.CalculateButton = uibutton(app.TwoPhaseEnvelopeonaTVDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.TwoPhaseEnvelopeonaTVDiagramLabel = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.TwoPhaseEnvelopeonaTVDiagramLabel.Text = 'Two-Phase Envelope on a TV Diagram';
            app.Table1 = uitable(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.Table1.ColumnName = {'Method'; 'Psat [bar]'; 'Tsat [K]'; 'Zliquid'; 'Zvapor'; 'Vliquid [m3/kg]'; 'Vvapor [m3/kg]'};
            app.Table1.RowName = {};
            app.Label = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.Label.Text = {'Symbology'; 'NaN: Not a Number.'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the temperature is less than the triple temperature.'; '**: Thermodynamically not possible case because the temperature is greater than the critical temperature.'};
            app.Label_2 = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.Label_2.Text = 'Critical Point';
            app.ExportButton = uibutton(app.TwoPhaseEnvelopeonaTVDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.TwoPhaseEnvelopeonaTVDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CubicEquationofStateDropDownLabel = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.TemperatureKLabel = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.CriticalTemperature = uieditfield(app.TwoPhaseEnvelopeonaTVDiagramUIFigure, 'text');
            app.CriticalTemperature.Editable = 'off';
            app.Volumem3kgEditFieldLabel = uilabel(app.TwoPhaseEnvelopeonaTVDiagramUIFigure);
            app.Volumem3kgEditFieldLabel.Text = 'Volume [m3/kg]';
            app.CriticalVolume = uieditfield(app.TwoPhaseEnvelopeonaTVDiagramUIFigure, 'text');
            app.CriticalVolume.Editable = 'off';
            % Show the figure after all components are created
            app.TwoPhaseEnvelopeonaTVDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TwoPhaseEnvelopeonaTVDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TwoPhaseEnvelopeonaTVDiagramUIFigure)
        end
    end
end