classdef Volume7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PVTDiagramgivenIsobarPressuresUIFigure  matlab.ui.Figure
        Compound               matlab.ui.control.DropDown
        CompoundDropDownLabel  matlab.ui.control.Label
        EoS                    matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        BackButton             matlab.ui.control.Button
        PVTDiagramgivenIsobarPressuresLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        CalculateButton        matlab.ui.control.Button
        Figure1                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.PVTDiagramgivenIsobarPressuresUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Volume;
            delete(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isobar Pressures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0
                progressbar=uiprogressdlg(app.PVTDiagramgivenIsobarPressuresUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                PVT_Diagram_given_Isobar_Pressures;
                close(progressbar);
            else
                msgbox("Input isobar pressures not found.","Data Status","error");
            end
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PVTDiagramgivenIsobarPressuresUIFigure = uifigure('Visible', 'off');
            app.PVTDiagramgivenIsobarPressuresUIFigure.Name = 'PVT Diagram given Isobar Pressures';
            app.PVTDiagramgivenIsobarPressuresUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.PVTDiagramgivenIsobarPressuresUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'T [K]')
            ylabel(app.Figure1, 'V [m^3/kg]')
            zlabel(app.Figure1, 'P [bar]')
            app.Figure1.XDir = 'reverse';
            app.Figure1.YScale = 'log';
            app.CalculateButton = uibutton(app.PVTDiagramgivenIsobarPressuresUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.PVTDiagramgivenIsobarPressuresUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.PVTDiagramgivenIsobarPressuresLabel = uilabel(app.PVTDiagramgivenIsobarPressuresUIFigure);
            app.PVTDiagramgivenIsobarPressuresLabel.Text = 'PVT Diagram given Isobar Pressures';
            app.BackButton = uibutton(app.PVTDiagramgivenIsobarPressuresUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CubicEquationofStateDropDownLabel = uilabel(app.PVTDiagramgivenIsobarPressuresUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.PVTDiagramgivenIsobarPressuresUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.PVTDiagramgivenIsobarPressuresUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.PVTDiagramgivenIsobarPressuresUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            % Show the figure after all components are created
            app.PVTDiagramgivenIsobarPressuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PVTDiagramgivenIsobarPressuresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PVTDiagramgivenIsobarPressuresUIFigure)
        end
    end
end