classdef Enthalpy5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TwoPhaseEnvelopeonaTHDiagramUIFigure  matlab.ui.Figure
        Label_4                      matlab.ui.control.Label
        ReferenceEnthalpy            matlab.ui.control.EditField
        EnthalpykJkgLabel            matlab.ui.control.Label
        ReferencePressure            matlab.ui.control.EditField
        PressurebarEditFieldLabel_2  matlab.ui.control.Label
        ReferenceTemperature         matlab.ui.control.EditField
        TemperatureKLabel            matlab.ui.control.Label
        ReferenceState               matlab.ui.control.EditField
        StateLabel                   matlab.ui.control.Label
        Compound                     matlab.ui.control.DropDown
        CompoundDropDownLabel        matlab.ui.control.Label
        EoS                          matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        TwoPhaseEnvelopeonaTHDiagramLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalculateButton              matlab.ui.control.Button
        CriticalEnthalpy             matlab.ui.control.EditField
        Enthlalpym3kgLabel           matlab.ui.control.Label
        CriticalTemperature          matlab.ui.control.EditField
        TemperatureKLabel_2          matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        Table1                       matlab.ui.control.Table
        Figure1                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.TwoPhaseEnvelopeonaTHDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,11)));
            if ismissing(table2array(data(app.Compound.Value,12))) || string(table2array(data(app.Compound.Value,12)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,12)));
            end
            if ismissing(table2array(data(app.Compound.Value,13))) || string(table2array(data(app.Compound.Value,13)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,13)));
            end
            if ismissing(table2array(data(app.Compound.Value,14))) || string(table2array(data(app.Compound.Value,14)))=="NaN"
                app.ReferenceEnthalpy.Value="NaN";
            else
                app.ReferenceEnthalpy.Value=string(table2array(data(app.Compound.Value,14)));
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isobar Pressures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0 && app.ReferenceState.Value~="NaN" && app.ReferenceTemperature.Value~="NaN" && app.ReferencePressure.Value~="NaN" && app.ReferenceEnthalpy.Value~="NaN"
                progressbar=uiprogressdlg(app.TwoPhaseEnvelopeonaTHDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Two_Phase_TH_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            elseif app.ReferenceState.Value=="NaN" || app.ReferenceTemperature.Value=="NaN" || app.ReferencePressure.Value=="NaN" || app.ReferenceEnthalpy.Value=="NaN"
                msgbox("Incomplete Data.","Data Status","error");
            else
                msgbox("Input isobar pressures not found.","Data Status","error");
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Enthalpy;
            delete(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.TwoPhaseEnvelopeonaTHDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)));
            table1.Properties.VariableNames{'Var1'}='Method';
            table1.Properties.VariableNames{'Var2'}='Psat [bar]';
            table1.Properties.VariableNames{'Var3'}='Tsat [K]';
            table1.Properties.VariableNames{'Var4'}='Zliquid';
            table1.Properties.VariableNames{'Var5'}='Zvapor';
            table1.Properties.VariableNames{'Var6'}='Hliquid [kJ/kg]';
            table1.Properties.VariableNames{'Var7'}='Hvapor [kJ/kg]';
            writecell(cell(5000,7),"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A1");
            writetable(table1,"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A9");
            writecell({'Compound'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A2");
            writecell({'Reference State'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A3");
            writecell({'Reference Temperature [K]'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A4");
            writecell({'Reference Pressure [bar]'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A5");
            writecell({'Reference Enthalpy [kJ/kg]'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A6");
            writecell({'Critical Temperature [K]'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A7");
            writecell({'Calculated Critical Enthalpy [kJ/kg]'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","A8");
            writecell({app.Compound.Value},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B1");
            writecell({app.EoS.Value},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B2");
            writecell({app.ReferenceState.Value},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B3");
            writematrix(str2double({app.ReferenceTemperature.Value}),"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B4");
            writematrix(str2double({app.ReferencePressure.Value}),"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B5");
            writematrix(str2double({app.ReferenceEnthalpy.Value}),"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B6");
            writematrix(str2double({app.CriticalTemperature.Value}),"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B7");
            writematrix(str2double({app.CriticalEnthalpy.Value}),"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range","B8");
            for i=12:3:5+height(table1)
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i,3,i,3])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['C',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i,4,i,4])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['D',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i,5,i,5])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['E',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i,6,i,6])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['F',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i,7,i,7])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['G',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i-2,3,i-2,3])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['C',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i-2,4,i-2,4])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['D',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i-2,5,i-2,5])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['E',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i-2,6,i-2,6])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['F',num2str(i-2)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",[i-2,7,i-2,7])))
                    writecell({'NaN'},"./Results/Two-Phase Envelope on a TH Diagram.xlsx","Sheet","TH Diagram","Range",['G',num2str(i-2)]);
                end
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound");
            app.CriticalTemperature.Value="";
            app.CriticalEnthalpy.Value="";
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,11)));
            if ismissing(table2array(data(app.Compound.Value,12))) || string(table2array(data(app.Compound.Value,12)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,12)));
            end
            if ismissing(table2array(data(app.Compound.Value,13))) || string(table2array(data(app.Compound.Value,13)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,13)));
            end
            if ismissing(table2array(data(app.Compound.Value,14))) || string(table2array(data(app.Compound.Value,14)))=="NaN"
                app.ReferenceEnthalpy.Value="NaN";
            else
                app.ReferenceEnthalpy.Value=string(table2array(data(app.Compound.Value,14)));
            end
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound");
            app.CriticalTemperature.Value="";
            app.CriticalEnthalpy.Value="";
            cla(app.Figure1);
            app.Table1.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TwoPhaseEnvelopeonaTHDiagramUIFigure = uifigure('Visible', 'off');
            app.TwoPhaseEnvelopeonaTHDiagramUIFigure.Name = 'Two-Phase Envelope on a TH Diagram';
            app.TwoPhaseEnvelopeonaTHDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'H [kJ/kg]')
            ylabel(app.Figure1, 'T_{sat} [K]')
            zlabel(app.Figure1, 'Z')
            app.Table1 = uitable(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Table1.ColumnName = {'Method'; 'Psat [bar]'; 'Tsat [K]'; 'Zliquid'; 'Zvapor'; 'Hliquid [kJ/kg]'; 'Hvapor [kJ/kg]'};
            app.Table1.RowName = {};
            app.Label_2 = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Label_2.Text = 'Calculated Critical Point';
            app.ExportButton = uibutton(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.TemperatureKLabel_2 = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.TemperatureKLabel_2.Text = 'Temperature [K]';
            app.CriticalTemperature = uieditfield(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'text');
            app.CriticalTemperature.Editable = 'off';
            app.Enthlalpym3kgLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Enthlalpym3kgLabel.Text = 'Enthalpy [kJ/kg]';
            app.CriticalEnthalpy = uieditfield(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'text');
            app.CriticalEnthalpy.Editable = 'off';
            app.CalculateButton = uibutton(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.TwoPhaseEnvelopeonaTHDiagramLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.TwoPhaseEnvelopeonaTHDiagramLabel.Text = 'Two-Phase Envelope on a TH Diagram';
            app.Label_3 = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Label_3.Text = 'Reference Data';
            app.CubicEquationofStateDropDownLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.StateLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.StateLabel.Text = 'State';
            app.ReferenceState = uieditfield(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'text');
            app.ReferenceState.Editable = 'off';
            app.TemperatureKLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.ReferenceTemperature = uieditfield(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'text');
            app.ReferenceTemperature.Editable = 'off';
            app.PressurebarEditFieldLabel_2 = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.PressurebarEditFieldLabel_2.Text = 'Pressure [bar]';
            app.ReferencePressure = uieditfield(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'text');
            app.ReferencePressure.Editable = 'off';
            app.EnthalpykJkgLabel = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.EnthalpykJkgLabel.Text = 'Enthalpy [kJ/kg]';
            app.ReferenceEnthalpy = uieditfield(app.TwoPhaseEnvelopeonaTHDiagramUIFigure, 'text');
            app.ReferenceEnthalpy.Editable = 'off';
            app.Label_4 = uilabel(app.TwoPhaseEnvelopeonaTHDiagramUIFigure);
            app.Label_4.Text = {'Symbology'; 'NaN: Not a Number.'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the temperature is less than the triple temperature.'; '**: Thermodynamically not possible case because the temperature is greater than the critical temperature.'};
            % Show the figure after all components are created
            app.TwoPhaseEnvelopeonaTHDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Enthalpy5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TwoPhaseEnvelopeonaTHDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TwoPhaseEnvelopeonaTHDiagramUIFigure)
        end
    end
end