classdef Enthalpy6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PHTDiagramgivenIsobarPressuresUIFigure  matlab.ui.Figure
        ReferenceEnthalpy          matlab.ui.control.EditField
        EnthalpykJkgLabel          matlab.ui.control.Label
        ReferencePressure          matlab.ui.control.EditField
        PressurebarEditFieldLabel  matlab.ui.control.Label
        ReferenceTemperature       matlab.ui.control.EditField
        TemperatureKLabel          matlab.ui.control.Label
        ReferenceState             matlab.ui.control.EditField
        StateLabel                 matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        Compound                   matlab.ui.control.DropDown
        CompoundDropDownLabel      matlab.ui.control.Label
        EoS                        matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        BackButton                 matlab.ui.control.Button
        PHTDiagramgivenIsobarPressuresLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        Figure1                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.PHTDiagramgivenIsobarPressuresUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,11)));
            if ismissing(table2array(data(app.Compound.Value,12))) || string(table2array(data(app.Compound.Value,12)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,12)));
            end
            if ismissing(table2array(data(app.Compound.Value,13))) || string(table2array(data(app.Compound.Value,13)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,13)));
            end
            if ismissing(table2array(data(app.Compound.Value,14))) || string(table2array(data(app.Compound.Value,14)))=="NaN"
                app.ReferenceEnthalpy.Value="NaN";
            else
                app.ReferenceEnthalpy.Value=string(table2array(data(app.Compound.Value,14)));
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Enthalpy;
            delete(app);
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,11)));
            if ismissing(table2array(data(app.Compound.Value,12))) || string(table2array(data(app.Compound.Value,12)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,12)));
            end
            if ismissing(table2array(data(app.Compound.Value,13))) || string(table2array(data(app.Compound.Value,13)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,13)));
            end
            if ismissing(table2array(data(app.Compound.Value,14))) || string(table2array(data(app.Compound.Value,14)))=="NaN"
                app.ReferenceEnthalpy.Value="NaN";
            else
                app.ReferenceEnthalpy.Value=string(table2array(data(app.Compound.Value,14)));
            end
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isobar Pressures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0 && app.ReferenceState.Value~="NaN" && app.ReferenceTemperature.Value~="NaN" && app.ReferencePressure.Value~="NaN" && app.ReferenceEnthalpy.Value~="NaN"
                progressbar=uiprogressdlg(app.PHTDiagramgivenIsobarPressuresUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                PHT_Diagram_given_Isobar_Pressures;
                close(progressbar);
            elseif app.ReferenceState.Value=="NaN" || app.ReferenceTemperature.Value=="NaN" || app.ReferencePressure.Value=="NaN" || app.ReferenceEnthalpy.Value=="NaN"
                msgbox("Incomplete Data.","Data Status","error");
            else
                msgbox("Input isobar pressures not found.","Data Status","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PHTDiagramgivenIsobarPressuresUIFigure = uifigure('Visible', 'off');
            app.PHTDiagramgivenIsobarPressuresUIFigure.Name = 'PHT Diagram given Isobar Pressures';
            app.PHTDiagramgivenIsobarPressuresUIFigure.Icon = 'Logoico.png';
            app.Figure1 = uiaxes(app.PHTDiagramgivenIsobarPressuresUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'T [K]')
            ylabel(app.Figure1, 'H [kJ/kg]')
            zlabel(app.Figure1, 'P [bar]')
            app.Figure1.XDir = 'reverse';
            app.Image = uiimage(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.PHTDiagramgivenIsobarPressuresLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.PHTDiagramgivenIsobarPressuresLabel.Text = 'PHT Diagram given Isobar Pressures';
            app.BackButton = uibutton(app.PHTDiagramgivenIsobarPressuresUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CubicEquationofStateDropDownLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.CalculateButton = uibutton(app.PHTDiagramgivenIsobarPressuresUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.Label.Text = 'Reference Data';
            app.StateLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.StateLabel.Text = 'State';
            app.ReferenceState = uieditfield(app.PHTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferenceState.Editable = 'off';
            app.TemperatureKLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.ReferenceTemperature = uieditfield(app.PHTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferenceTemperature.Editable = 'off';
            app.PressurebarEditFieldLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.PressurebarEditFieldLabel.Text = 'Pressure [bar]';
            app.ReferencePressure = uieditfield(app.PHTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferencePressure.Editable = 'off';
            app.EnthalpykJkgLabel = uilabel(app.PHTDiagramgivenIsobarPressuresUIFigure);
            app.EnthalpykJkgLabel.Text = 'Enthalpy [kJ/kg]';
            app.ReferenceEnthalpy = uieditfield(app.PHTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferenceEnthalpy.Editable = 'off';
            % Show the figure after all components are created
            app.PHTDiagramgivenIsobarPressuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Enthalpy6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PHTDiagramgivenIsobarPressuresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PHTDiagramgivenIsobarPressuresUIFigure)
        end
    end
end