classdef Entropy6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PSTDiagramgivenIsobarPressuresUIFigure  matlab.ui.Figure
        ReferenceEntropy           matlab.ui.control.EditField
        EnthalpykJkgLabel          matlab.ui.control.Label
        ReferencePressure          matlab.ui.control.EditField
        PressurebarEditFieldLabel  matlab.ui.control.Label
        ReferenceTemperature       matlab.ui.control.EditField
        TemperatureKLabel          matlab.ui.control.Label
        ReferenceState             matlab.ui.control.EditField
        StateLabel                 matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        Compound                   matlab.ui.control.DropDown
        CompoundDropDownLabel      matlab.ui.control.Label
        EoS                        matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        BackButton                 matlab.ui.control.Button
        PSTDiagramgivenIsobarPressuresLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        Figure1                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.PSTDiagramgivenIsobarPressuresUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,15)));
            if ismissing(table2array(data(app.Compound.Value,16))) || string(table2array(data(app.Compound.Value,16)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,16)));
            end
            if ismissing(table2array(data(app.Compound.Value,17))) || string(table2array(data(app.Compound.Value,17)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,17)));
            end
            if ismissing(table2array(data(app.Compound.Value,18))) || string(table2array(data(app.Compound.Value,18)))=="NaN"
                app.ReferenceEntropy.Value="NaN";
            else
                app.ReferenceEntropy.Value=string(table2array(data(app.Compound.Value,18)));
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Entropy;
            delete(app);
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,15)));
            if ismissing(table2array(data(app.Compound.Value,16))) || string(table2array(data(app.Compound.Value,16)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,16)));
            end
            if ismissing(table2array(data(app.Compound.Value,17))) || string(table2array(data(app.Compound.Value,17)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,17)));
            end
            if ismissing(table2array(data(app.Compound.Value,18))) || string(table2array(data(app.Compound.Value,18)))=="NaN"
                app.ReferenceEntropy.Value="NaN";
            else
                app.ReferenceEntropy.Value=string(table2array(data(app.Compound.Value,18)));
            end
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isobar Pressures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,2))) && string(table2array(data2(i,2)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0 && app.ReferenceState.Value~="NaN" && app.ReferenceTemperature.Value~="NaN" && app.ReferencePressure.Value~="NaN" && app.ReferenceEntropy.Value~="NaN"
                progressbar=uiprogressdlg(app.PSTDiagramgivenIsobarPressuresUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                PST_Diagram_given_Isobar_Pressures;
                close(progressbar);
            elseif app.ReferenceState.Value=="NaN" || app.ReferenceTemperature.Value=="NaN" || app.ReferencePressure.Value=="NaN" || app.ReferenceEntropy.Value=="NaN"
                msgbox("Incomplete Data.","Data Status","error");
            else
                msgbox("Input isobar pressures not found.","Data Status","error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PSTDiagramgivenIsobarPressuresUIFigure = uifigure('Visible', 'off');
            app.PSTDiagramgivenIsobarPressuresUIFigure.Name = 'PST Diagram given Isobar Pressures';
            app.PSTDiagramgivenIsobarPressuresUIFigure.Icon = 'Logoico.png';
            app.Figure1 = uiaxes(app.PSTDiagramgivenIsobarPressuresUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'T [K]')
            ylabel(app.Figure1, 'S [kJ/kg/K]')
            zlabel(app.Figure1, 'P [bar]')
            app.Figure1.XDir = 'reverse';
            app.Image = uiimage(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.PSTDiagramgivenIsobarPressuresLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.PSTDiagramgivenIsobarPressuresLabel.Text = 'PST Diagram given Isobar Pressures';
            app.BackButton = uibutton(app.PSTDiagramgivenIsobarPressuresUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CubicEquationofStateDropDownLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.CalculateButton = uibutton(app.PSTDiagramgivenIsobarPressuresUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.Label.Text = 'Reference Data';
            app.StateLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.StateLabel.Text = 'State';
            app.ReferenceState = uieditfield(app.PSTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferenceState.Editable = 'off';
            app.TemperatureKLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.ReferenceTemperature = uieditfield(app.PSTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferenceTemperature.Editable = 'off';
            app.PressurebarEditFieldLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.PressurebarEditFieldLabel.Text = 'Pressure [bar]';
            app.ReferencePressure = uieditfield(app.PSTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferencePressure.Editable = 'off';
            app.EnthalpykJkgLabel = uilabel(app.PSTDiagramgivenIsobarPressuresUIFigure);
            app.EnthalpykJkgLabel.Text = 'Entropy [kJ/kg/K]';
            app.ReferenceEntropy = uieditfield(app.PSTDiagramgivenIsobarPressuresUIFigure, 'text');
            app.ReferenceEntropy.Editable = 'off';
            % Show the figure after all components are created
            app.PSTDiagramgivenIsobarPressuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entropy6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PSTDiagramgivenIsobarPressuresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PSTDiagramgivenIsobarPressuresUIFigure)
        end
    end
end