classdef Volume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VolumeUIFigure       matlab.ui.Figure
        PVTDiagramgivenIsobarPressuresButton  matlab.ui.control.Button
        TwoPhaseEnvelopeonaTVDiagramButton  matlab.ui.control.Button
        IsobargivenPressureonaTVDiagramButton  matlab.ui.control.Button
        PsatgivenTapplyingDifferentsBracketingMethods  matlab.ui.control.Button
        BackButton           matlab.ui.control.Button
        ChooseanoptionLabel  matlab.ui.control.Label
        PVTDiagramgivenIsothermTemperaturesButton  matlab.ui.control.Button
        TwoPhaseEnvelopeonaPVDiagramButton  matlab.ui.control.Button
        IsothermgivenTemperatureonaPVDiagramButton  matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.VolumeUIFigure,'center');
        end

        % Button pushed function: 
        % IsothermgivenTemperatureonaPVDiagramButton
        function IsothermgivenTemperatureonaPVDiagramButtonPushed(app, event)
            Volume1;
            delete(app);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            EoS_Simulator;
            delete(app);
        end

        % Button pushed function: TwoPhaseEnvelopeonaPVDiagramButton
        function TwoPhaseEnvelopeonaPVDiagramButtonPushed(app, event)
            Volume2;
            delete(app);
        end

        % Button pushed function: PVTDiagramgivenIsothermTemperaturesButton
        function PVTDiagramgivenIsothermTemperaturesButtonPushed(app, event)
            Volume3;
            delete(app); 
        end

        % Button pushed function: 
        % PsatgivenTapplyingDifferentsBracketingMethods
        function PsatgivenTapplyingDifferentsBracketingMethodsButtonPushed(app, event)
            Volume4;
            delete(app); 
        end

        % Button pushed function: IsobargivenPressureonaTVDiagramButton
        function IsobargivenPressureonaTVDiagramButtonPushed(app, event)
            Volume5;
            delete(app); 
        end

        % Button pushed function: TwoPhaseEnvelopeonaTVDiagramButton
        function TwoPhaseEnvelopeonaTVDiagramButtonPushed(app, event)
            Volume6;
            delete(app); 
        end

        % Button pushed function: PVTDiagramgivenIsobarPressuresButton
        function PVTDiagramgivenIsobarPressuresButtonPushed(app, event)
            Volume7;
            delete(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VolumeUIFigure = uifigure('Visible', 'off');
            app.VolumeUIFigure.Name = 'Volume';
            app.VolumeUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Image = uiimage(app.VolumeUIFigure);
            app.Image.ImageSource = 'Logo.PNG';
            app.IsothermgivenTemperatureonaPVDiagramButton = uibutton(app.VolumeUIFigure, 'push');
            app.IsothermgivenTemperatureonaPVDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @IsothermgivenTemperatureonaPVDiagramButtonPushed, true);
            app.IsothermgivenTemperatureonaPVDiagramButton.Text = 'Isotherm given Temperature on a PV Diagram';
            app.TwoPhaseEnvelopeonaPVDiagramButton = uibutton(app.VolumeUIFigure, 'push');
            app.TwoPhaseEnvelopeonaPVDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @TwoPhaseEnvelopeonaPVDiagramButtonPushed, true);
            app.TwoPhaseEnvelopeonaPVDiagramButton.Text = 'Two-Phase Envelope on a PV Diagram';
            app.PVTDiagramgivenIsothermTemperaturesButton = uibutton(app.VolumeUIFigure, 'push');
            app.PVTDiagramgivenIsothermTemperaturesButton.ButtonPushedFcn = createCallbackFcn(app, @PVTDiagramgivenIsothermTemperaturesButtonPushed, true);
            app.PVTDiagramgivenIsothermTemperaturesButton.Text = 'PVT Diagram given Isotherm Temperatures';
            app.ChooseanoptionLabel = uilabel(app.VolumeUIFigure);
            app.ChooseanoptionLabel.Text = 'Choose an option';
            app.BackButton = uibutton(app.VolumeUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.PsatgivenTapplyingDifferentsBracketingMethods = uibutton(app.VolumeUIFigure, 'push');
            app.PsatgivenTapplyingDifferentsBracketingMethods.ButtonPushedFcn = createCallbackFcn(app, @PsatgivenTapplyingDifferentsBracketingMethodsButtonPushed, true);
            app.PsatgivenTapplyingDifferentsBracketingMethods.Text = 'Saturation Pressure given Temperature applying Differents Bracketing Methods';
            app.IsobargivenPressureonaTVDiagramButton = uibutton(app.VolumeUIFigure, 'push');
            app.IsobargivenPressureonaTVDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @IsobargivenPressureonaTVDiagramButtonPushed, true);
            app.IsobargivenPressureonaTVDiagramButton.Text = 'Isobar given Pressure on a TV Diagram';
            app.TwoPhaseEnvelopeonaTVDiagramButton = uibutton(app.VolumeUIFigure, 'push');
            app.TwoPhaseEnvelopeonaTVDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @TwoPhaseEnvelopeonaTVDiagramButtonPushed, true);
            app.TwoPhaseEnvelopeonaTVDiagramButton.Text = 'Two-Phase Envelope on a TV Diagram';
            app.PVTDiagramgivenIsobarPressuresButton = uibutton(app.VolumeUIFigure, 'push');
            app.PVTDiagramgivenIsobarPressuresButton.ButtonPushedFcn = createCallbackFcn(app, @PVTDiagramgivenIsobarPressuresButtonPushed, true);
            app.PVTDiagramgivenIsobarPressuresButton.Text = 'PVT Diagram given Isobar Pressures';
            % Show the figure after all components are created
            app.VolumeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VolumeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VolumeUIFigure)
        end
    end
end