classdef Volume3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PVTDiagramgivenIsothermTemperaturesUIFigure  matlab.ui.Figure
        Compound               matlab.ui.control.DropDown
        CompoundDropDownLabel  matlab.ui.control.Label
        EoS                    matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        BackButton             matlab.ui.control.Button
        PVTDiagramgivenIsothermTemperaturesLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        CalculateButton        matlab.ui.control.Button
        Figure1                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.PVTDiagramgivenIsothermTemperaturesUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isotherm Temperatures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,1))) && string(table2array(data2(i,1)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0
                progressbar=uiprogressdlg(app.PVTDiagramgivenIsothermTemperaturesUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                PVT_Diagram_given_Isotherm_Temperatures;
                close(progressbar);
            else
                msgbox("Input isotherm temperatures not found.","Data Status","error");
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Volume;
            delete(app);
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            title(app.Figure1,"EoS - Compound");
            cla(app.Figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PVTDiagramgivenIsothermTemperaturesUIFigure = uifigure('Visible', 'off');
            app.PVTDiagramgivenIsothermTemperaturesUIFigure.Name = 'PVT Diagram given Isotherm Temperatures';
            app.PVTDiagramgivenIsothermTemperaturesUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'T [K]')
            ylabel(app.Figure1, 'V [m^3/kg]')
            zlabel(app.Figure1, 'P [bar]')
            app.Figure1.XDir = 'reverse';
            app.Figure1.YScale = 'log';
            app.CalculateButton = uibutton(app.PVTDiagramgivenIsothermTemperaturesUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.PVTDiagramgivenIsothermTemperaturesLabel = uilabel(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            app.PVTDiagramgivenIsothermTemperaturesLabel.Text = 'PVT Diagram given Isotherm Temperatures';
            app.BackButton = uibutton(app.PVTDiagramgivenIsothermTemperaturesUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CubicEquationofStateDropDownLabel = uilabel(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.PVTDiagramgivenIsothermTemperaturesUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            % Show the figure after all components are created
            app.PVTDiagramgivenIsothermTemperaturesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Volume3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PVTDiagramgivenIsothermTemperaturesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PVTDiagramgivenIsothermTemperaturesUIFigure)
        end
    end
end