classdef Entropy2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TwoPhaseEnvelopeonaPSDiagramUIFigure  matlab.ui.Figure
        ReferenceEntropy             matlab.ui.control.EditField
        EnthalpykJkgLabel            matlab.ui.control.Label
        ReferencePressure            matlab.ui.control.EditField
        PressurebarEditFieldLabel_2  matlab.ui.control.Label
        ReferenceTemperature         matlab.ui.control.EditField
        TemperatureKLabel            matlab.ui.control.Label
        ReferenceState               matlab.ui.control.EditField
        StateLabel                   matlab.ui.control.Label
        Compound                     matlab.ui.control.DropDown
        CompoundDropDownLabel        matlab.ui.control.Label
        EoS                          matlab.ui.control.DropDown
        CubicEquationofStateDropDownLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        TwoPhaseEnvelopeonaPSDiagramLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalculateButton              matlab.ui.control.Button
        CriticalEntropy              matlab.ui.control.EditField
        Enthlalpym3kgLabel           matlab.ui.control.Label
        CriticalPressure             matlab.ui.control.EditField
        PressurebarEditFieldLabel    matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Table1                       matlab.ui.control.Table
        Figure1                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            movegui(app.TwoPhaseEnvelopeonaPSDiagramUIFigure,"north");
            % Compound
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds");
            app.Compound.Items=table2cell(data(:,1));
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,15)));
            if ismissing(table2array(data(app.Compound.Value,16))) || string(table2array(data(app.Compound.Value,16)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,16)));
            end
            if ismissing(table2array(data(app.Compound.Value,17))) || string(table2array(data(app.Compound.Value,17)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,17)));
            end
            if ismissing(table2array(data(app.Compound.Value,18))) || string(table2array(data(app.Compound.Value,18)))=="NaN"
                app.ReferenceEntropy.Value="NaN";
            else
                app.ReferenceEntropy.Value=string(table2array(data(app.Compound.Value,18)));
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Isotherm Temperatures
            data2=readtable("Thermodynamic Data.xlsx","Sheet",convertCharsToStrings(app.Compound.Value));
            tam=0;
            for i=1:height(data2)
                if ~ismissing(table2array(data2(i,1))) && string(table2array(data2(i,1)))~="NaN"
                    tam=tam+1;
                    break;
                end
            end
            if tam>0 && app.ReferenceState.Value~="NaN" && app.ReferenceTemperature.Value~="NaN" && app.ReferencePressure.Value~="NaN" && app.ReferenceEntropy.Value~="NaN"
                progressbar=uiprogressdlg(app.TwoPhaseEnvelopeonaPSDiagramUIFigure,"Title","Status","Message","Calculating","Indeterminate","on","Cancelable","off");
                drawnow;
                Two_Phase_PS_Diagram;
                app.ExportButton.Visible="on";
                close(progressbar);
            elseif app.ReferenceState.Value=="NaN" || app.ReferenceTemperature.Value=="NaN" || app.ReferencePressure.Value=="NaN" || app.ReferenceEntropy.Value=="NaN"
                msgbox("Incomplete Data.","Data Status","error");
            else
                msgbox("Input isotherm temperatures not found.","Data Status","error");
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            Entropy;
            delete(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            progressbar=uiprogressdlg(app.TwoPhaseEnvelopeonaPSDiagramUIFigure,"Title","Status","Message","Exporting","Indeterminate","on","Cancelable","off");
            drawnow;
            aux=app.Table1.Data;
            table1=table(aux(:,1),str2double(aux(:,2)),str2double(aux(:,3)),str2double(aux(:,4)),str2double(aux(:,5)),str2double(aux(:,6)),str2double(aux(:,7)));
            table1.Properties.VariableNames{'Var1'}='Method';
            table1.Properties.VariableNames{'Var2'}='Tsat [K]';
            table1.Properties.VariableNames{'Var3'}='Psat [bar]';
            table1.Properties.VariableNames{'Var4'}='Zliquid';
            table1.Properties.VariableNames{'Var5'}='Zvapor';
            table1.Properties.VariableNames{'Var6'}='Sliquid [kJ/kg/K]';
            table1.Properties.VariableNames{'Var7'}='Svapor [kJ/kg/K]';
            writecell(cell(5000,7),"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A1");
            writetable(table1,"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A9");
            writecell({'Compound'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A1");
            writecell({'Cubic Equation of State'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A2");
            writecell({'Reference State'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A3");
            writecell({'Reference Temperature [K]'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A4");
            writecell({'Reference Pressure [bar]'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A5");
            writecell({'Reference Entropy [kJ/kg/K]'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A6");
            writecell({'Critical Pressure [bar]'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A7");
            writecell({'Calculated Critical Entropy [kJ/kg/K]'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","A8");
            writecell({app.Compound.Value},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B1");
            writecell({app.EoS.Value},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B2");
            writecell({app.ReferenceState.Value},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B3");
            writematrix(str2double({app.ReferenceTemperature.Value}),"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B4");
            writematrix(str2double({app.ReferencePressure.Value}),"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B5");
            writematrix(str2double({app.ReferenceEntropy.Value}),"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B6");
            writematrix(str2double({app.CriticalPressure.Value}),"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B7");
            writematrix(str2double({app.CriticalEntropy.Value}),"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range","B8");
            for i=12:3:5+height(table1)
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",[i,3,i,3])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",['C',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",[i,4,i,4])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",['D',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",[i,5,i,5])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",['E',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",[i,6,i,6])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",['F',num2str(i)]);
                end
                if ismissing(string(readcell("./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",[i,7,i,7])))
                    writecell({'NA'},"./Results/Two-Phase Envelope on a PS Diagram.xlsx","Sheet","PS Diagram","Range",['G',num2str(i)]);
                end
            end
            close(progressbar);
            msgbox("Finished Export.","Export Status","help");
        end

        % Value changed function: Compound
        function CompoundValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound");
            app.CriticalPressure.Value="";
            app.CriticalEntropy.Value="";
            % Reference State
            data=readtable("Thermodynamic Data.xlsx","Sheet","Compounds","ReadRowNames",1);
            app.ReferenceState.Value=string(table2array(data(app.Compound.Value,15)));
            if ismissing(table2array(data(app.Compound.Value,16))) || string(table2array(data(app.Compound.Value,16)))=="NaN"
                app.ReferenceTemperature.Value="NaN";
            else
                app.ReferenceTemperature.Value=string(table2array(data(app.Compound.Value,16)));
            end
            if ismissing(table2array(data(app.Compound.Value,17))) || string(table2array(data(app.Compound.Value,17)))=="NaN"
                app.ReferencePressure.Value="NaN";
            else
                app.ReferencePressure.Value=string(table2array(data(app.Compound.Value,17)));
            end
            if ismissing(table2array(data(app.Compound.Value,18))) || string(table2array(data(app.Compound.Value,18)))=="NaN"
                app.ReferenceEntropy.Value="NaN";
            else
                app.ReferenceEntropy.Value=string(table2array(data(app.Compound.Value,18)));
            end
            cla(app.Figure1);
            app.Table1.Data={};
        end

        % Value changed function: EoS
        function EoSValueChanged(app, event)
            clc;
            app.ExportButton.Visible="off";
            title(app.Figure1,"EoS - Compound");
            app.CriticalPressure.Value="";
            app.CriticalEntropy.Value="";
            cla(app.Figure1);
            app.Table1.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TwoPhaseEnvelopeonaPSDiagramUIFigure = uifigure('Visible', 'off');
            app.TwoPhaseEnvelopeonaPSDiagramUIFigure.Name = 'Two-Phase Envelope on a PS Diagram';
            app.TwoPhaseEnvelopeonaPSDiagramUIFigure.Icon = fullfile(pathToMLAPP, 'Logoico.png');
            app.Figure1 = uiaxes(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            title(app.Figure1, 'EoS - Compound')
            xlabel(app.Figure1, 'S [kJ/kg/K]')
            ylabel(app.Figure1, 'P_{sat} [bar]')
            zlabel(app.Figure1, 'Z')
            app.Table1 = uitable(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Table1.ColumnName = {'Method'; 'Tsat [K]'; 'Psat [bar]'; 'Zliquid'; 'Zvapor'; 'Sliquid [kJ/kg/K]'; 'Svapor [kJ/kg/K]'};
            app.Table1.RowName = {};
            app.Label = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Label.Text = {'Symbology'; 'NA: Not Available.'; '*: Thermodynamically not possible case because the pressure is less than the triple pressure.'; '**: Thermodynamically not possible case because the pressure is greater than the critical pressure.'};
            app.Label_2 = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Label_2.Text = 'Calculated Critical Point';
            app.ExportButton = uibutton(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Visible = 'off';
            app.ExportButton.Text = 'Export';
            app.BackButton = uibutton(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.PressurebarEditFieldLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.PressurebarEditFieldLabel.Text = 'Pressure [bar]';
            app.CriticalPressure = uieditfield(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'text');
            app.CriticalPressure.Editable = 'off';
            app.Enthlalpym3kgLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Enthlalpym3kgLabel.Text = 'Entropy [kJ/kg/K]';
            app.CriticalEntropy = uieditfield(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'text');
            app.CriticalEntropy.Editable = 'off';
            app.CalculateButton = uibutton(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logoico.png');
            app.TwoPhaseEnvelopeonaPSDiagramLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.TwoPhaseEnvelopeonaPSDiagramLabel.Text = 'Two-Phase Envelope on a PS Diagram';
            app.Label_3 = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Label_3.Text = 'Reference Data';
            app.CubicEquationofStateDropDownLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.CubicEquationofStateDropDownLabel.Text = 'Cubic Equation of State';
            app.EoS = uidropdown(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.EoS.Items = {'van der Waals', 'Redlich-Kwong', 'Soave-Redlich-Kwong', 'Peng-Robinson'};
            app.EoS.ValueChangedFcn = createCallbackFcn(app, @EoSValueChanged, true);
            app.EoS.Value = 'van der Waals';
            app.CompoundDropDownLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.CompoundDropDownLabel.Text = 'Compound';
            app.Compound = uidropdown(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.Compound.Items = {};
            app.Compound.ValueChangedFcn = createCallbackFcn(app, @CompoundValueChanged, true);
            app.Compound.Value = {};
            app.StateLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.StateLabel.Text = 'State';
            app.ReferenceState = uieditfield(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'text');
            app.ReferenceState.Editable = 'off';
            app.TemperatureKLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.TemperatureKLabel.Text = 'Temperature [K]';
            app.ReferenceTemperature = uieditfield(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'text');
            app.ReferenceTemperature.Editable = 'off';
            app.PressurebarEditFieldLabel_2 = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.PressurebarEditFieldLabel_2.Text = 'Pressure [bar]';
            app.ReferencePressure = uieditfield(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'text');
            app.ReferencePressure.Editable = 'off';
            app.EnthalpykJkgLabel = uilabel(app.TwoPhaseEnvelopeonaPSDiagramUIFigure);
            app.EnthalpykJkgLabel.Text = 'Entropy [kJ/kg/K]';
            app.ReferenceEntropy = uieditfield(app.TwoPhaseEnvelopeonaPSDiagramUIFigure, 'text');
            app.ReferenceEntropy.Editable = 'off';
            % Show the figure after all components are created
            app.TwoPhaseEnvelopeonaPSDiagramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Entropy2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TwoPhaseEnvelopeonaPSDiagramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TwoPhaseEnvelopeonaPSDiagramUIFigure)
        end
    end
end