classdef PdM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcesadordemedicionesUIFigure  matlab.ui.Figure
        TodasMediciones                 matlab.ui.control.Button
        PromediosGroup                  matlab.ui.container.Panel
        FV1                             matlab.ui.control.EditField
        FV1Label                        matlab.ui.control.Label
        FIT1                            matlab.ui.control.EditField
        FIT1Label                       matlab.ui.control.Label
        FT2                             matlab.ui.control.EditField
        FT2Label                        matlab.ui.control.Label
        PV2                             matlab.ui.control.EditField
        PV2Label                        matlab.ui.control.Label
        FIC1                            matlab.ui.control.EditField
        FIC1Label                       matlab.ui.control.Label
        TV1                             matlab.ui.control.EditField
        TV1Label                        matlab.ui.control.Label
        TT7                             matlab.ui.control.EditField
        TT7Label                        matlab.ui.control.Label
        TT6                             matlab.ui.control.EditField
        TT6Label                        matlab.ui.control.Label
        PT2                             matlab.ui.control.EditField
        PT2Label                        matlab.ui.control.Label
        TT5                             matlab.ui.control.EditField
        TT5Label                        matlab.ui.control.Label
        TT4                             matlab.ui.control.EditField
        TT4Label                        matlab.ui.control.Label
        TT3                             matlab.ui.control.EditField
        TT3Label                        matlab.ui.control.Label
        TT2                             matlab.ui.control.EditField
        TT2Label                        matlab.ui.control.Label
        TT1                             matlab.ui.control.EditField
        TT1Label                        matlab.ui.control.Label
        LV1                             matlab.ui.control.EditField
        LV1Label                        matlab.ui.control.Label
        LT1                             matlab.ui.control.EditField
        LT1Label                        matlab.ui.control.Label
        FT1                             matlab.ui.control.EditField
        FT1Label                        matlab.ui.control.Label
        PV1                             matlab.ui.control.EditField
        PV1Label                        matlab.ui.control.Label
        PT1                             matlab.ui.control.EditField
        PT1Label                        matlab.ui.control.Label
        LIT1                            matlab.ui.control.EditField
        LIT1Label                       matlab.ui.control.Label
        Rango                           matlab.ui.control.RangeSlider
        MedicionesGroup                 matlab.ui.container.Panel
        M2                              matlab.ui.control.DropDown
        M2Label                         matlab.ui.control.Label
        M1                              matlab.ui.control.DropDown
        M1Label                         matlab.ui.control.Label
        DefMediciones                   matlab.ui.control.Button
        ProcesadordemedicionesGroup     matlab.ui.container.Panel
        TipodeCaso                      matlab.ui.control.DropDown
        TipodeCasoLabel                 matlab.ui.control.Label
        Fin                             matlab.ui.control.EditField
        FinLabel                        matlab.ui.control.Label
        Inicio                          matlab.ui.control.EditField
        InicioLabel                     matlab.ui.control.Label
        Caso                            matlab.ui.control.DropDown
        CasoLabel                       matlab.ui.control.Label
        Tplot                           matlab.ui.control.UIAxes
        M1plot                          matlab.ui.control.UIAxes
        M2plot                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Cdp % Rangos de casos
        fechas % Fechas en los datos disponibles
        datos % Datos de las 20 variables
        variables % Lista de variables
        variableslabel % Etiquetas de eje Y para gráficas
        indices % Índices de los casos de acuerdo a su tipo
        rini % Dato de fecha inicial del caso seleccionado
        rfin % Dato de fecha final del caso seleccionado
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Extraer datos de Casos_de_prueba.mat y Todo.mat e inicializar opciones de "Procesador de mediciones" y "Mediciones" al ejecutar aplicación
            clc; app.variables=["LIT-1","PT-1","PV-1","FT-1","LT-1","LV-1","TT-1","TT-2","TT-3","TT-4","TT-5","PT-2","TT-6","TT-7","TV-1","FIC-1","PV-2","FT-2","FIT-1","FV-1"]; 
            app.variableslabel=["LIT-1 [%]","PT-1 [kg/cm^2]","PV-1 [%]","FT-1 [kg/h]","LT-1 [%]","LV-1 [%]","TT-1 [°C]","TT-2 [°C]","TT-3 [°C]","TT-4 [°C]","TT-5 [°C]","PT-2 [kg/cm^2]","TT-6 [°C]","TT-7 [°C]","TV-1 [%]","FIC-1 [m^3/h]","PV-2 [%]","FT-2 [L/h]","FIT-1 [kg/h]","FV-1 [%]"];
            aux=load("Casos_de_prueba.mat"); app.Cdp=aux.Casos_de_prueba; app.Caso.Items=app.Cdp.Caso; app.indices=[1:length(app.Cdp.Caso)]';
            aux=load("Todo.mat"); app.fechas=aux.dfr; app.datos=table(aux.datos(:,1),aux.datos(:,2),aux.datos(:,3),aux.datos(:,4),aux.datos(:,5),aux.datos(:,6),aux.datos(:,7),aux.datos(:,8),aux.datos(:,9),aux.datos(:,10),aux.datos(:,11),aux.datos(:,12),aux.datos(:,13),aux.datos(:,14),aux.datos(:,15),aux.datos(:,16),aux.datos(:,17),aux.datos(:,18),aux.datos(:,19),aux.datos(:,20)); app.datos.Properties.VariableNames=app.variables;
            app.rini=app.Cdp.Dato_Inicial(app.indices(app.Caso.ValueIndex)); app.rfin=app.Cdp.Dato_Final(app.indices(app.Caso.ValueIndex));
            app.Inicio.Value=string(datetime(app.fechas(app.rini),"Format","dd-MM-yyyy HH:mm")); app.Fin.Value=string(datetime(app.fechas(app.rfin),"Format","dd-MM-yyyy HH:mm"));
            app.M1.Items=app.variables; app.M1.ValueIndex=1; app.M2.Items=app.variables; app.M2.ValueIndex=1;
        end

        % Value changed function: TipodeCaso
        function TipodeCasoValueChanged(app, event)
            % Borrar resultados anteriores e inicializar opciones de "Procesador de mediciones" al cambiar valor de "Tipo de caso"
            cla(app.Tplot); cla(app.M1plot); cla(app.M2plot); app.Rango.Visible="off"; app.Tplot.Visible="off"; app.M1plot.Visible="off"; app.M2plot.Visible="off"; tdC=app.TipodeCaso.Value;
            app.TodasMediciones.Visible="off"; app.PromediosGroup.Visible="off";
            if tdC=="Todo" app.Caso.Items=app.Cdp.Caso; app.indices=[1:length(app.Cdp.Caso)]';
            else  app.Caso.Items=app.Cdp.Caso(contains(app.Cdp.Caso,tdC)); app.indices=find(contains(app.Cdp.Caso,tdC));
            end
            app.Caso.ValueIndex=1;
            app.rini=app.Cdp.Dato_Inicial(app.indices(app.Caso.ValueIndex)); app.rfin=app.Cdp.Dato_Final(app.indices(app.Caso.ValueIndex));
            app.Inicio.Value=strcat(string(datetime(app.fechas(app.rini),"Format","dd-MM-yyyy HH:mm")),", dato ", string(app.rini)); app.Fin.Value=strcat(string(datetime(app.fechas(app.rfin),"Format","dd-MM-yyyy HH:mm")),", dato ", string(app.rfin));
        end

        % Value changed function: Caso
        function CasoValueChanged(app, event)
           % Borrar resultados anteriores e inicializar opciones de "Procesador de mediciones" al cambiar valor de "Caso"
           cla(app.Tplot); cla(app.M1plot); cla(app.M2plot); app.Tplot.Visible="off"; app.M1plot.Visible="off"; app.M2plot.Visible="off"; app.Rango.Visible="off";
           app.TodasMediciones.Visible="off"; app.PromediosGroup.Visible="off";
           app.rini=app.Cdp.Dato_Inicial(app.indices(app.Caso.ValueIndex)); app.rfin=app.Cdp.Dato_Final(app.indices(app.Caso.ValueIndex));
           app.Inicio.Value=strcat(string(datetime(app.fechas(app.rini),"Format","dd-MM-yyyy HH:mm")),", dato ", string(app.rini)); app.Fin.Value=strcat(string(datetime(app.fechas(app.rfin),"Format","dd-MM-yyyy HH:mm")),", dato ", string(app.rfin));
        end

        % Button pushed function: DefMediciones
        function DefMedicionesButtonPushed(app, event)
            % Mostrar gráficas (límites ajustados en caso de su máximo=mínimo) y promedios del caso seleccionado al presionar la palomita en "Mediciones"
            if app.Rango.Visible=="off"
                app.Rango.Limits=[app.rini,app.rfin]; app.Rango.Value(1)=app.rini; app.Rango.Value(2)=app.rfin; 
            end
            app.Rango.Visible="on"; app.Tplot.Visible="on"; app.M1plot.Visible="on"; app.M2plot.Visible="on"; cla(app.M1plot); cla(app.M2plot);
            app.TodasMediciones.Visible="on"; app.PromediosGroup.Visible="on";
            if contains(app.Caso.Value,"Preparación o vacío") colorplot="#ff0000";
            elseif contains(app.Caso.Value,"Estado dinámico") colorplot="#ffc000";
            elseif contains(app.Caso.Value,"Estado acotado") colorplot="#083464";
            elseif contains(app.Caso.Value,"Estado estacionario") colorplot="#92d050";
            else colorplot="#1a1a1b";
            end
            app.rini=round(app.Rango.Value(1)); app.rfin=round(app.Rango.Value(2));
            if app.rini==app.rfin
                if app.rfin==app.rfin app.rini=app.rfin-1; else app.rfin=app.rini+1; end
            end
            plot(app.M1plot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,app.M1.Value)),"Color",colorplot,"LineWidth",1.5);
            plot(app.M2plot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,app.M2.Value)),"Color",colorplot,"LineWidth",1.5);
            maxY1=max(table2array(app.datos(app.rini:app.rfin,app.M1.Value))); maxY2=max(table2array(app.datos(app.rini:app.rfin,app.M2.Value)));
            minY1=min(table2array(app.datos(app.rini:app.rfin,app.M1.Value))); minY2=min(table2array(app.datos(app.rini:app.rfin,app.M2.Value)));
            xlim(app.M1plot,[app.fechas(app.rini) app.fechas(app.rfin)]); xlim(app.M2plot,[app.fechas(app.rini) app.fechas(app.rfin)]);
            if maxY1~=minY1 ylim(app.M1plot,[minY1-(maxY1-minY1)*0.05 maxY1+(maxY1-minY1)*0.05]);
            elseif maxY1~=0 ylim(app.M1plot,[minY1*0.95 minY1*1.05]);
            else ylim(app.M1plot,[-0.05 0.05]);
            end
            if maxY2~=minY2 ylim(app.M2plot,[minY2-(maxY2-minY2)*0.05 maxY2+(maxY2-minY2)*0.05]);
            elseif maxY2~=0 ylim(app.M2plot,[minY2*0.95 minY2*1.05]);
            else ylim(app.M2plot,[-0.05 0.05]);
            end
            hold(app.Tplot,"on");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-1")),"Color","#43189a","LineWidth",1.5,"LineStyle","-");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-2")),"Color","#50258c","LineWidth",1.5,"LineStyle","--");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-3")),"Color","#5c1d94","LineWidth",1.5,"LineStyle",":");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-4")),"Color","#842d85","LineWidth",1.5,"LineStyle","-");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-5")),"Color","#a30f9d","LineWidth",1.5,"LineStyle","--");
            xlim(app.Tplot,[app.fechas(app.rini) app.fechas(app.rfin)]); ylabel(app.Tplot,"TT [°C]","FontName","Century Schoolbook","FontWeight","bold"); legend(app.Tplot,"TT-1","TT-2","TT-3","TT-4","TT-5","FontName","Century Schoolbook","Location","eastoutside","Box","off");
            hold(app.Tplot,"off");
            app.LIT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(1))))); app.PT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(2)))));
            app.PV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(3))))); auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(4))); app.FT1.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1));
            app.LT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(5))))); app.LV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(6)))));
            app.TT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(7))))); app.TT2.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(8)))));
            app.TT3.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(9))))); app.TT4.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(10)))));
            app.TT5.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(11))))); app.PT2.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(12)))));
            app.TT6.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(13))))); app.TT7.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(14)))));
            app.TV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(15))))); auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(16))); app.FIC1.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1));
            app.PV2.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(17))))); auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(18))); app.FT2.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1));
            auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(19))); app.FIT1.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1)); app.FV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(20)))));
            ylabel(app.M1plot,app.variableslabel(app.M1.ValueIndex),"FontName","Century Schoolbook","FontWeight","bold"); ylabel(app.M2plot,app.variableslabel(app.M2.ValueIndex),"FontName","Century Schoolbook","FontWeight","bold");
        end

        % Value changed function: Rango
        function RangoValueChanged(app, event)
            % Actualizar gráficas y promedios al modificar el rango del caso al mover control deslizante
            cla(app.Tplot); cla(app.M1plot); cla(app.M2plot); 
            if contains(app.Caso.Value,"Preparación o vacío") colorplot="#ff0000";
            elseif contains(app.Caso.Value,"Estado dinámico") colorplot="#ffc000";
            elseif contains(app.Caso.Value,"Estado acotado") colorplot="#083464";
            elseif contains(app.Caso.Value,"Estado estacionario") colorplot="#92d050";
            else colorplot="#1a1a1b";
            end
            app.rini=round(app.Rango.Value(1)); app.rfin=round(app.Rango.Value(2));
            if app.rini==app.rfin
                if app.rfin==app.rfin app.rini=app.rfin-1; else app.rfin=app.rini+1; end
            end
            app.Inicio.Value=strcat(string(datetime(app.fechas(app.rini),"Format","dd-MM-yyyy HH:mm")),", dato ", string(app.rini)); app.Fin.Value=strcat(string(datetime(app.fechas(app.rfin),"Format","dd-MM-yyyy HH:mm")),", dato ", string(app.rfin));
            plot(app.M1plot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,app.M1.Value)),"Color",colorplot,"LineWidth",1.5);
            plot(app.M2plot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,app.M2.Value)),"Color",colorplot,"LineWidth",1.5);
            maxY1=max(table2array(app.datos(app.rini:app.rfin,app.M1.Value))); maxY2=max(table2array(app.datos(app.rini:app.rfin,app.M2.Value)));
            minY1=min(table2array(app.datos(app.rini:app.rfin,app.M1.Value))); minY2=min(table2array(app.datos(app.rini:app.rfin,app.M2.Value)));
            xlim(app.M1plot,[app.fechas(app.rini) app.fechas(app.rfin)]); xlim(app.M2plot,[app.fechas(app.rini) app.fechas(app.rfin)]);
            if maxY1~=minY1 ylim(app.M1plot,[minY1-(maxY1-minY1)*0.05 maxY1+(maxY1-minY1)*0.05]);
            elseif maxY1~=0 ylim(app.M1plot,[minY1*0.95 minY1*1.05]);
            else ylim(app.M1plot,[-0.05 0.05]);
            end
            if maxY2~=minY2 ylim(app.M2plot,[minY2-(maxY2-minY2)*0.05 maxY2+(maxY2-minY2)*0.05]);
            elseif maxY2~=0 ylim(app.M2plot,[minY2*0.95 minY2*1.05]);
            else ylim(app.M2plot,[-0.05 0.05]);
            end   
            hold(app.Tplot,"on");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-1")),"Color","#43189a","LineWidth",1.5,"LineStyle","-");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-2")),"Color","#50258c","LineWidth",1.5,"LineStyle","--");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-3")),"Color","#5c1d94","LineWidth",1.5,"LineStyle",":");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-4")),"Color","#842d85","LineWidth",1.5,"LineStyle","-");
            plot(app.Tplot,app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,"TT-5")),"Color","#a30f9d","LineWidth",1.5,"LineStyle","--");
            xlim(app.Tplot,[app.fechas(app.rini) app.fechas(app.rfin)]); ylabel(app.Tplot,"TT [°C]","FontName","Century Schoolbook","FontWeight","bold"); legend(app.Tplot,"TT-1","TT-2","TT-3","TT-4","TT-5","Interpreter","tex","Location","eastoutside","Box","off");
            hold(app.Tplot,"off");
            app.LIT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(1))))); app.PT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(2)))));
            app.PV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(3))))); auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(4))); app.FT1.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1));
            app.LT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(5))))); app.LV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(6)))));
            app.TT1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(7))))); app.TT2.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(8)))));
            app.TT3.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(9))))); app.TT4.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(10)))));
            app.TT5.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(11))))); app.PT2.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(12)))));
            app.TT6.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(13))))); app.TT7.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(14)))));
            app.TV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(15))))); app.FIC1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(16)))));
            app.PV2.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(17))))); auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(18))); app.FT2.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1));
            auxmean=table2array(app.datos(app.rini:app.rfin,app.variables(19))); app.FIT1.Value=string(sum(auxmean(auxmean>0))/(app.rfin-app.rini+1)); app.FV1.Value=string(mean(table2array(app.datos(app.rini:app.rfin,app.variables(20)))));
            ylabel(app.M1plot,app.variableslabel(app.M1.ValueIndex),"FontName","Century Schoolbook","FontWeight","bold"); ylabel(app.M2plot,app.variableslabel(app.M2.ValueIndex),"FontName","Century Schoolbook","FontWeight","bold");
        end

        % Value changed function: M1
        function M1ValueChanged(app, event)
            % Limpiar gráfica de M1 al cambiarlo
            cla(app.M1plot); ylabel(app.M1plot,"")
        end

        % Value changed function: M2
        function M2ValueChanged(app, event)
            % Limpiar gráfica de M2 al cambiarlo
            cla(app.M2plot); ylabel(app.M2plot,"")
        end

        % Button pushed function: TodasMediciones
        function TodasMedicionesButtonPushed(app, event)
            % Mostrar todas las mediciones en el rango especificado al dar click en "Todas las mediciones"
            if contains(app.Caso.Value,"Preparación o vacío") colorplot="#ff0000";
            elseif contains(app.Caso.Value,"Estado dinámico") colorplot="#ffc000";
            elseif contains(app.Caso.Value,"Estado acotado") colorplot="#083464";
            elseif contains(app.Caso.Value,"Estado estacionario") colorplot="#92d050";
            else colorplot="#1a1a1b";
            end
            figure("Name",strcat("Todas las Mediciones 1: ",app.Inicio.Value, " a ",app.Fin.Value),"NumberTitle","off","units","normalized","outerposition",[0 0 1 1],"Color","w");
            for i=1:2
                for j=1:5
                    subplot(2,5,(i-1)*5+j);
                    plot(app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,app.variables((i-1)*5+j))),"Color",colorplot,"LineWidth",1.5);
                    maxY1=max(table2array(app.datos(app.rini:app.rfin,app.variables((i-1)*5+j)))); minY1=min(table2array(app.datos(app.rini:app.rfin,app.variables((i-1)*5+j)))); 
                    xlim([app.fechas(app.rini) app.fechas(app.rfin)]); ylabel(app.variableslabel((i-1)*5+j),"FontName","Century Schoolbook","FontWeight","bold");
                    if maxY1~=minY1 ylim([minY1-(maxY1-minY1)*0.05 maxY1+(maxY1-minY1)*0.05]);
                    elseif maxY1~=0 ylim([minY1*0.95 minY1*1.05]);
                    else ylim([-0.05 0.05]);
                    end
                    grid on;
                end
            end
            figure("Name",strcat("Todas las Mediciones 2: ",app.Inicio.Value, " a ",app.Fin.Value),"NumberTitle","off","units","normalized","outerposition",[0 0 1 1],"Color","w" );
            for i=1:2
                for j=1:5
                    subplot(2,5,(i-1)*5+j); 
                    plot(app.fechas(app.rini:app.rfin),table2array(app.datos(app.rini:app.rfin,app.variables(10+(i-1)*5+j))),"Color",colorplot,"LineWidth",1.5);
                    maxY1=max(table2array(app.datos(app.rini:app.rfin,app.variables(10+(i-1)*5+j)))); minY1=min(table2array(app.datos(app.rini:app.rfin,app.variables(10+(i-1)*5+j)))); 
                    xlim([app.fechas(app.rini) app.fechas(app.rfin)]); ylabel(app.variableslabel(10+(i-1)*5+j),"FontName","Century Schoolbook","FontWeight","bold");
                    if maxY1~=minY1 ylim([minY1-(maxY1-minY1)*0.05 maxY1+(maxY1-minY1)*0.05]);
                    elseif maxY1~=0 ylim([minY1*0.95 minY1*1.05]);
                    else ylim([-0.05 0.05]);
                    end
                    grid on;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ProcesadordemedicionesUIFigure = uifigure('Visible', 'off');
            app.ProcesadordemedicionesUIFigure.Name = 'Procesador de mediciones';
            app.M2plot = uiaxes(app.ProcesadordemedicionesUIFigure);
            app.M2plot.Visible = 'off';
            app.M1plot = uiaxes(app.ProcesadordemedicionesUIFigure);
            app.M1plot.Visible = 'off';
            app.Tplot = uiaxes(app.ProcesadordemedicionesUIFigure);
            app.Tplot.Visible = 'off';
            app.ProcesadordemedicionesGroup = uipanel(app.ProcesadordemedicionesUIFigure);
            app.ProcesadordemedicionesGroup.Title = 'Procesador de mediciones';
            app.CasoLabel = uilabel(app.ProcesadordemedicionesGroup);
            app.CasoLabel.Text = 'Caso';
            app.Caso = uidropdown(app.ProcesadordemedicionesGroup);
            app.Caso.ValueChangedFcn = createCallbackFcn(app, @CasoValueChanged, true);
            app.InicioLabel = uilabel(app.ProcesadordemedicionesGroup);
            app.InicioLabel.Text = 'Inicio';
            app.Inicio = uieditfield(app.ProcesadordemedicionesGroup, 'text');
            app.Inicio.Editable = 'off';
            app.FinLabel = uilabel(app.ProcesadordemedicionesGroup);
            app.FinLabel.Text = 'Fin';
            app.Fin = uieditfield(app.ProcesadordemedicionesGroup, 'text');
            app.Fin.Editable = 'off';
            app.TipodeCasoLabel = uilabel(app.ProcesadordemedicionesGroup);
            app.TipodeCasoLabel.Text = 'Tipo de Caso';
            app.TipodeCaso = uidropdown(app.ProcesadordemedicionesGroup);
            app.TipodeCaso.Items = {'Todo', 'Preparación o vacío', 'Estado dinámico', 'Estado acotado', 'Estado estacionario'};
            app.TipodeCaso.ValueChangedFcn = createCallbackFcn(app, @TipodeCasoValueChanged, true);
            app.TipodeCaso.Value = 'Todo';
            app.MedicionesGroup = uipanel(app.ProcesadordemedicionesUIFigure);
            app.MedicionesGroup.Title = 'Mediciones';
            app.DefMediciones = uibutton(app.MedicionesGroup, 'push');
            app.DefMediciones.ButtonPushedFcn = createCallbackFcn(app, @DefMedicionesButtonPushed, true);
            app.DefMediciones.Icon = fullfile(pathToMLAPP, 'cheque.png');
            app.DefMediciones.Text = '';
            app.M1Label = uilabel(app.MedicionesGroup);
            app.M1Label.Text = 'M1';
            app.M1 = uidropdown(app.MedicionesGroup);
            app.M1.ValueChangedFcn = createCallbackFcn(app, @M1ValueChanged, true);
            app.M2Label = uilabel(app.MedicionesGroup);
            app.M2Label.Text = 'M2';
            app.M2 = uidropdown(app.MedicionesGroup);
            app.M2.ValueChangedFcn = createCallbackFcn(app, @M2ValueChanged, true);
            app.Rango = uislider(app.ProcesadordemedicionesUIFigure, 'range');
            app.Rango.Limits = [0 10000];
            app.Rango.MajorTicks = [];
            app.Rango.ValueChangedFcn = createCallbackFcn(app, @RangoValueChanged, true);
            app.Rango.MinorTicks = [];
            app.Rango.Visible = 'off';
            app.Rango.Value = [0 10000];
            app.PromediosGroup = uipanel(app.ProcesadordemedicionesUIFigure);
            app.PromediosGroup.Title = 'Promedios';
            app.PromediosGroup.Visible = 'off';
            app.LIT1Label = uilabel(app.PromediosGroup);
            app.LIT1Label.Interpreter = 'tex';
            app.LIT1Label.Text = 'LIT-1 [%]';
            app.LIT1 = uieditfield(app.PromediosGroup, 'text');
            app.LIT1.Editable = 'off';
            app.PT1Label = uilabel(app.PromediosGroup);
            app.PT1Label.Interpreter = 'tex';
            app.PT1Label.Text = 'PT-1 [kg/cm^2]';
            app.PT1 = uieditfield(app.PromediosGroup, 'text');
            app.PT1.Editable = 'off';
            app.PV1Label = uilabel(app.PromediosGroup);
            app.PV1Label.Interpreter = 'tex';
            app.PV1Label.Text = 'PV-1 [%]';
            app.PV1 = uieditfield(app.PromediosGroup, 'text');
            app.PV1.Editable = 'off';
            app.FT1Label = uilabel(app.PromediosGroup);
            app.FT1Label.Interpreter = 'tex';
            app.FT1Label.Text = 'FT-1 [kg/h]';
            app.FT1 = uieditfield(app.PromediosGroup, 'text');
            app.FT1.Editable = 'off';
            app.LT1Label = uilabel(app.PromediosGroup);
            app.LT1Label.Interpreter = 'tex';
            app.LT1Label.Text = 'LT-1 [%]';
            app.LT1 = uieditfield(app.PromediosGroup, 'text');
            app.LT1.Editable = 'off';
            app.LV1Label = uilabel(app.PromediosGroup);
            app.LV1Label.Interpreter = 'tex';
            app.LV1Label.Text = 'LV-1 [%]';
            app.LV1 = uieditfield(app.PromediosGroup, 'text');
            app.LV1.Editable = 'off';
            app.TT1Label = uilabel(app.PromediosGroup);
            app.TT1Label.Interpreter = 'tex';
            app.TT1Label.Text = 'TT-1 [°C]';
            app.TT1 = uieditfield(app.PromediosGroup, 'text');
            app.TT1.Editable = 'off';
            app.TT2Label = uilabel(app.PromediosGroup);
            app.TT2Label.Interpreter = 'tex';
            app.TT2Label.Text = 'TT-2 [°C]';
            app.TT2 = uieditfield(app.PromediosGroup, 'text');
            app.TT2.Editable = 'off';
            app.TT3Label = uilabel(app.PromediosGroup);
            app.TT3Label.Interpreter = 'tex';
            app.TT3Label.Text = 'TT-3 [°C]';
            app.TT3 = uieditfield(app.PromediosGroup, 'text');
            app.TT3.Editable = 'off';
            app.TT4Label = uilabel(app.PromediosGroup);
            app.TT4Label.Interpreter = 'tex';
            app.TT4Label.Text = 'TT-4 [°C]';
            app.TT4 = uieditfield(app.PromediosGroup, 'text');
            app.TT4.Editable = 'off';
            app.TT5Label = uilabel(app.PromediosGroup);
            app.TT5Label.Interpreter = 'tex';
            app.TT5Label.Text = 'TT-5 [°C]';
            app.TT5 = uieditfield(app.PromediosGroup, 'text');
            app.TT5.Editable = 'off';
            app.PT2Label = uilabel(app.PromediosGroup);
            app.PT2Label.Interpreter = 'tex';
            app.PT2Label.Text = 'PT-2 [kg/cm^2]';
            app.PT2 = uieditfield(app.PromediosGroup, 'text');
            app.PT2.Editable = 'off';
            app.TT6Label = uilabel(app.PromediosGroup);
            app.TT6Label.Interpreter = 'tex';
            app.TT6Label.Text = 'TT-6 [°C]';
            app.TT6 = uieditfield(app.PromediosGroup, 'text');
            app.TT6.Editable = 'off';
            app.TT7Label = uilabel(app.PromediosGroup);
            app.TT7Label.Interpreter = 'tex';
            app.TT7Label.Text = 'TT-7 [°C]';
            app.TT7 = uieditfield(app.PromediosGroup, 'text');
            app.TT7.Editable = 'off';
            app.TV1Label = uilabel(app.PromediosGroup);
            app.TV1Label.Interpreter = 'tex';
            app.TV1Label.Text = 'TV-1 [%]';
            app.TV1 = uieditfield(app.PromediosGroup, 'text');
            app.TV1.Editable = 'off';
            app.FIC1Label = uilabel(app.PromediosGroup);
            app.FIC1Label.Interpreter = 'tex';
            app.FIC1Label.Text = 'FIC-1 [m^3/h]';
            app.FIC1 = uieditfield(app.PromediosGroup, 'text');
            app.FIC1.Editable = 'off';
            app.PV2Label = uilabel(app.PromediosGroup);
            app.PV2Label.Interpreter = 'tex';
            app.PV2Label.Text = 'PV-2 [%]';
            app.PV2 = uieditfield(app.PromediosGroup, 'text');
            app.PV2.Editable = 'off';
            app.FT2Label = uilabel(app.PromediosGroup);
            app.FT2Label.Interpreter = 'tex';
            app.FT2Label.Text = 'FT-2 [L/h]';
            app.FT2 = uieditfield(app.PromediosGroup, 'text');
            app.FT2.Editable = 'off';
            app.FIT1Label = uilabel(app.PromediosGroup);
            app.FIT1Label.Interpreter = 'tex';
            app.FIT1Label.Text = 'FIT-1 [kg/h]';
            app.FIT1 = uieditfield(app.PromediosGroup, 'text');
            app.FIT1.Editable = 'off';
            app.FV1Label = uilabel(app.PromediosGroup);
            app.FV1Label.Interpreter = 'tex';
            app.FV1Label.Text = 'FV-1 [%]';
            app.FV1 = uieditfield(app.PromediosGroup, 'text');
            app.FV1.Editable = 'off';
            app.TodasMediciones = uibutton(app.ProcesadordemedicionesUIFigure, 'push');
            app.TodasMediciones.ButtonPushedFcn = createCallbackFcn(app, @TodasMedicionesButtonPushed, true);
            app.TodasMediciones.Visible = 'off';
            app.TodasMediciones.Text = 'Todas las mediciones';
            % Show the figure after all components are created
            app.ProcesadordemedicionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PdM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcesadordemedicionesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcesadordemedicionesUIFigure)
        end
    end
end